classdef Gait_And_Emg_Signals_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GaitandEmgsignalsAnalyzerUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GaitandEmgAnalyzerPanel         matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        LoginTab                        matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        usernameEditFieldLabel_2        matlab.ui.control.Label
        passwordEditField               wt.PasswordField
        Image                           matlab.ui.control.Image
        PleaseLoginLabel                matlab.ui.control.Label
        WelcometoGaitandEmgSignalsAnalyzerLabel  matlab.ui.control.Label
        LoginButton                     matlab.ui.control.Button
        UsernameEditField               matlab.ui.control.EditField
        UsernameLabel                   matlab.ui.control.Label
        SignupTab                       matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        Image_2                         matlab.ui.control.Image
        WelcometoGaitandEmgSignalsAnalyzerLabel_2  matlab.ui.control.Label
        passwordEditField_2             matlab.ui.control.EditField
        passwordEditField_2Label        matlab.ui.control.Label
        emailEditField                  matlab.ui.control.EditField
        emailEditFieldLabel             matlab.ui.control.Label
        usernameEditField_2             matlab.ui.control.EditField
        usernameEditField_2Label        matlab.ui.control.Label
        SignupButton                    matlab.ui.control.Button
        helpTab                         matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        Image_3                         matlab.ui.control.Image
        HTML2                           matlab.ui.control.HTML
        TabGroup                        matlab.ui.container.TabGroup
        homeTab                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        lastNameEditField               matlab.ui.control.EditField
        patientslastnameEditFieldLabel  matlab.ui.control.Label
        firstNameEditField              matlab.ui.control.EditField
        patientsfirstnameEditFieldLabel  matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        saveButton                      matlab.ui.control.Button
        uploadButton                    matlab.ui.control.Button
        gaitTab                         matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SaveasaPDFButton                matlab.ui.control.Button
        GaitTextArea                    matlab.ui.control.TextArea
        SingleGaitCycleButton           matlab.ui.control.Button
        JointTypeDropDown               matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        jointmovementButton             matlab.ui.control.Button
        MultipleGaitCyclesButton        matlab.ui.control.Button
        emgTab                          matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        PlotPSDButton                   matlab.ui.control.Button
        EmgCyclesButton                 matlab.ui.control.Button
        SaveasPDFButton                 matlab.ui.control.Button
        GenerateReportButton            matlab.ui.control.Button
        EMGTextArea                     matlab.ui.control.TextArea
        filteredEmgButton               matlab.ui.control.Button
        rawemgButton                    matlab.ui.control.Button
        DropDown_3                      matlab.ui.control.DropDown
        filtersTab                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        MovAvgLabel                     matlab.ui.control.Label
        RMSLabel                        matlab.ui.control.Label
        HighPassLabel                   matlab.ui.control.Label
        LowPassLabel                    matlab.ui.control.Label
        RectifyModeDropDown             matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        MovAvgSlider                    matlab.ui.control.Slider
        RectifyCheckBox                 matlab.ui.control.CheckBox
        MovAvgCheckBox                  matlab.ui.control.CheckBox
        HighPassSlider                  matlab.ui.control.Slider
        LowPassSlider                   matlab.ui.control.Slider
        RMSCheckBox                     matlab.ui.control.CheckBox
        HighPassCheckBox                matlab.ui.control.CheckBox
        LowPassCheckBox                 matlab.ui.control.CheckBox
        ReportTab                       matlab.ui.container.Tab
        PreviousReportTextArea          matlab.ui.control.TextArea
        ReportTextArea                  matlab.ui.control.TextArea
        settingsTab                     matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        plotstatisticdatainafigureCheckBox  matlab.ui.control.CheckBox
        getdataTab                      matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        LoadReportButton                matlab.ui.control.Button
        LoadAllDataButton               matlab.ui.control.Button
        sessionDropDown                 matlab.ui.control.DropDown
        sessionDropDownLabel            matlab.ui.control.Label
        GetsessionsButton               matlab.ui.control.Button
        patientnameEditField            matlab.ui.control.EditField
        patientnameEditFieldLabel       matlab.ui.control.Label
        HowToUseTab                     matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        HTML                            matlab.ui.control.HTML
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end


    properties (Access = private)

        tree
        SelectedSignal      % 'Ankle', 'Knee', or 'Hip'
        joint
        movementType
        channels
        fs
        time
        EmgData
        emgNum
        filteredEmg

        filteredTime
       
        Conn          % your database connection
        CurrentUserID     % id of the logged-in user, if you track it
        LoadedData
        loadedTree
        LoggedInUserID   % stores the user_id of the currently logged-in user

        numberOfGaitCycles %number of gait cycles extracted from the data to be showed in the report
        StrikesLeft
        StrikesRight
        emgResampled
        emgTimeResampled
        emgCyclesLeft
        emgCyclesRight
        emgCycleTimesLeft % Description
        emgCycleTimesRight
        plotstatisticdatainafigure
        StatsFigure % Description
        
        uploadedMvnxPath % Description
        SelectedSessionID % Description

       
    end

 methods (Access = private)


        function filtered = applyLowPass(~, signal, fs, cutoff)
            [b, a] = butter(4, cutoff / (fs/2), 'low');
            filtered = filtfilt(b, a, signal);
        end


        function filtered = applyHighPass(~, signal, fs, cutoff)
            [b, a] = butter(4, cutoff / (fs/2), 'high');
            filtered = filtfilt(b, a, signal);
        end


        function applyFilteredEMG(app)
            % Select the raw EMG signal for the chosen channel
            signal = app.EmgData(app.emgNum, :);
            selectedText = app.DropDown_3.Value;
            fs = app.fs(app.emgNum);

            % Validate input data
            if isempty(app.EmgData)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please load EMG data first', 'No Data');
                return;
            end

            % Warn if RMS is enabled without rectification
            if app.RMSCheckBox.Value && ~app.RectifyCheckBox.Value
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ...
                    'Warning: RMS is enabled without rectification. This may lead to incorrect envelope calculation. Consider enabling rectification.', ...
                    'Processing Warning');
            end

            % Warn if high-pass cutoff is greater than or equal to low-pass cutoff
            if app.HighPassCheckBox.Value && app.LowPassCheckBox.Value
                if app.HighPassSlider.Value >= app.LowPassSlider.Value
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ...
                        sprintf('Warning: High-pass cutoff (%d Hz) is greater than or equal to low-pass cutoff (%d Hz). This may remove all signal content.', ...
                        app.HighPassSlider.Value, app.LowPassSlider.Value), ...
                        'Filter Warning');
                end
            end

            % Recommended order: High-Pass -> Low-Pass -> Rectify -> RMS -> Moving Average
            % Apply each step only if enabled

            % Step 1: High-Pass Filter
            if app.HighPassCheckBox.Value
                hpCutoff = app.HighPassSlider.Value;
                signal = applyHighPass(app, signal, fs, hpCutoff);
            end

            % Step 2: Low-Pass Filter
            if app.LowPassCheckBox.Value
                lpCutoff = app.LowPassSlider.Value;
                signal = applyLowPass(app, signal, fs, lpCutoff);
            end

            % Step 3: Rectification
            if app.RectifyCheckBox.Value
                mode = app.RectifyModeDropDown.Value;
                signal = applyRectify(app, signal, mode);
                if strcmp(mode, 'full') && any(signal < 0)
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Full rectification failed: negative values detected.', 'Error');
                end
            end

            % Step 4: RMS
            if app.RMSCheckBox.Value
                signal = applyRMS(app, signal, fs);
            end

            % Step 5: Moving Average
            if app.MovAvgCheckBox.Value
                winMs = app.MovAvgSlider.Value; % 10–200 ms slider
                signal = applyMovingAverage(app, signal, fs, winMs);
            end

            % Assign the final filtered signal
            app.filteredEmg = signal;

            % Recalculate or fetch time vector
            if size(app.time, 1) == size(app.EmgData, 1)
                emgTime = app.time(app.emgNum, :);
            else
                emgTime = (0:length(signal)-1) / fs;
            end
            app.filteredTime = emgTime;

            % Plot filtered EMG
            cla(app.UIAxes_2);
            plot(app.UIAxes_2, emgTime, app.filteredEmg);
            xlabel(app.UIAxes_2, 'Time (s)');
            ylabel(app.UIAxes_2, sprintf('Filtered EMG of %s', selectedText));
            title(app.UIAxes_2, sprintf('Filtered EMG Signal - Channel %s', selectedText));
        end


        function maSignal = applyMovingAverage(~, signal, fs, windowMs)
            % Convert ms to samples
            N = max(1, round((windowMs/1000)*fs));
            % Compute moving average of the squared signal, then sqrt for RMS-like
            % (or just movmean(signal, N) for plain MA)
            maSignal = movmean(signal, N, 'Endpoints','shrink');
        end


        function rmsSignal = applyRMS(~, signal, fs)
            % Define the fixed window size in milliseconds (10ms)
            windowMs = 10;  % Fixed window size of 10ms

            % Calculate the number of samples corresponding to the window size
            N = round(windowMs / 1000 * fs);  % Convert ms to samples

            % Apply the RMS filter
            rmsSignal = sqrt(movmean(signal.^2, N, 'Endpoints', 'shrink'));
        end


        function sigOut = applyRectify(~, sigIn, mode)
            switch mode
                case 'full'    % full-wave
                    sigOut = abs(sigIn);
                case 'half'    % half-wave
                    sigOut = max(sigIn, 0);
                otherwise
                    sigOut = sigIn;
            end
        end

        %----------=============================================================================================================

        function hashedPass = hashPassword(~, password)
            % Use Java's PBKDF2 for secure password hashing
            import javax.crypto.SecretKeyFactory
            import javax.crypto.spec.PBEKeySpec
            import java.security.SecureRandom

            % Convert MATLAB string to Java string
            javaPassword = java.lang.String(password);

            % Generate a random salt
            salt = SecureRandom.getInstance('SHA1PRNG');
            saltBytes = zeros(1, 16, 'uint8');
            salt.nextBytes(saltBytes);

            % PBKDF2 parameters
            iterations = 100000; % High iteration count for security
            keyLength = 256; % Key length in bits
            spec = PBEKeySpec(javaPassword.toCharArray(), saltBytes, iterations, keyLength);
            factory = SecretKeyFactory.getInstance('PBKDF2WithHmacSHA256');
            hash = factory.generateSecret(spec).getEncoded();

            % Combine salt and hash for storage
            saltStr = sprintf('%02x', typecast(saltBytes, 'uint8'));
            hashStr = sprintf('%02x', typecast(hash, 'uint8'));
            hashedPass = sprintf('%s:%s', saltStr, hashStr); % Format: salt:hash
        end


        function isMatch = verifyPassword(~, password, storedHash)
            % Verify password against stored hash
            import javax.crypto.SecretKeyFactory
            import javax.crypto.spec.PBEKeySpec

            % Convert MATLAB string to Java string
            javaPassword = java.lang.String(password);

            % Split stored hash into salt and hash
            parts = split(storedHash, ':');
            if numel(parts) ~= 2
                isMatch = false;
                return;
            end
            saltStr = parts{1};
            storedHashStr = parts{2};

            % Convert salt from hex to bytes
            saltBytes = uint8(hex2dec(reshape(saltStr, 2, [])'));

            % PBKDF2 parameters
            iterations = 100000;
            keyLength = 256;
            spec = PBEKeySpec(javaPassword.toCharArray(), saltBytes, iterations, keyLength);
            factory = SecretKeyFactory.getInstance('PBKDF2WithHmacSHA256');
            hash = factory.generateSecret(spec).getEncoded();
            hashStr = sprintf('%02x', typecast(hash, 'uint8'));

            % Compare hashes
            isMatch = strcmp(hashStr, storedHashStr);
        end


        function loadMat(app, fullFilePath)
            % Load the MAT file
            loadedData = load(fullFilePath);

            % Validate loaded data structure
            requiredFields = {'Data', 'Channels', 'Fs', 'Time'};
            if ~all(isfield(loadedData, requiredFields))
                missingFields = setdiff(requiredFields, fieldnames(loadedData));
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('MAT file missing required fields: %s', strjoin(missingFields, ', ')), ...
                    'Data Error', 'Icon', 'error');
                return;
            end

            % Store data in app properties
            app.EmgData = loadedData.Data; % 12×260982 double
            app.channels = loadedData.Channels;
            app.fs = loadedData.Fs; % 12×1 double
            app.time = loadedData.Time; % 1×260982 double

            % Remove redundant app.LoadedData and app.data
            % app.LoadedData = []; % Uncomment if no longer needed
            % app.data = []; % Uncomment if no longer needed

            % Process channel names for DropDown_3
            ChannelsList = cellstr(app.channels);
            for i = 1:numel(ChannelsList)
                splitText = strsplit(ChannelsList{i}, ':');
                ChannelsList{i} = strtrim(splitText{1}); % Keep only part before ':' and trim whitespace
            end
            app.DropDown_3.Items = ChannelsList;

            % Trigger raw and filtered EMG processing
            rawemgButtonPushed(app);
            filteredEmgButtonPushed(app);

            % Optional: Display loaded data for debugging
            disp('Loaded MAT file contents:');
            disp(loadedData);
        end


        function means = extractMeanLeft(~,heelStrikesLeft, LeftflexAngle)
            means = arrayfun(@(i) mean(LeftflexAngle(heelStrikesLeft(i):heelStrikesLeft(i+1))), ...
                1:length(heelStrikesLeft)-1);
        end

        function means = extractMeanRight(~,heelStrikesRight, RightflexAngle)
            means = arrayfun(@(i) mean(RightflexAngle(heelStrikesRight(i):heelStrikesRight(i+1))), ...
                1:length(heelStrikesRight)-1);
        end

        function maxVals = extractMaxLeft(~,heelStrikesLeft, LeftflexAngle)
            maxVals = arrayfun(@(i) max(LeftflexAngle(heelStrikesLeft(i):heelStrikesLeft(i+1))), ...
                1:length(heelStrikesLeft)-1);
        end

        function maxVals = extractMaxRight(~,heelStrikesRight, RightflexAngle)
            maxVals = arrayfun(@(i) max(RightflexAngle(heelStrikesRight(i):heelStrikesRight(i+1))), ...
                1:length(heelStrikesRight)-1);
        end
        function minVals = extractMinLeft(~,heelStrikesLeft, LeftflexAngle)
            minVals = arrayfun(@(i) min(LeftflexAngle(heelStrikesLeft(i):heelStrikesLeft(i+1))), ...
                1:length(heelStrikesLeft)-1);
        end

        function minVals = extractMinRight(~,heelStrikesRight, RightflexAngle)
            minVals = arrayfun(@(i) min(RightflexAngle(heelStrikesRight(i):heelStrikesRight(i+1))), ...
                1:length(heelStrikesRight)-1);
        end
        function rom = extractRangeLeft(~, heelStrikesLeft, LeftflexAngle)
            rom = arrayfun(@(i) ...
                max(LeftflexAngle(heelStrikesLeft(i):heelStrikesLeft(i+1))) - ...
                min(LeftflexAngle(heelStrikesLeft(i):heelStrikesLeft(i+1))), ...
                1:length(heelStrikesLeft)-1);
        end


        function rom = extractRangeRight(~, heelStrikesRight, RightflexAngle)
            rom = arrayfun(@(i) ...
                max(RightflexAngle(heelStrikesRight(i):heelStrikesRight(i+1))) - ...
                min(RightflexAngle(heelStrikesRight(i):heelStrikesRight(i+1))), ...
                1:length(heelStrikesRight)-1);
        end

        function stdVals = extractStdLeft(~,heelStrikesLeft, LeftflexAngle)
            stdVals = arrayfun(@(i) std(LeftflexAngle(heelStrikesLeft(i):heelStrikesLeft(i+1))), ...
                1:length(heelStrikesLeft)-1);
        end

        function stdVals = extractStdRight(~,heelStrikesRight, RightflexAngle)
            stdVals = arrayfun(@(i) std(RightflexAngle(heelStrikesRight(i):heelStrikesRight(i+1))), ...
                1:length(heelStrikesRight)-1);
        end

        function durations = extractDurationLeft(~,heelStrikesLeft)
            durations = arrayfun(@(i) heelStrikesLeft(i+1) - heelStrikesLeft(i), ...
                1:length(heelStrikesLeft)-1);
        end

        function durations = extractDurationRight(~,heelStrikesRight)
            durations = arrayfun(@(i) heelStrikesRight(i+1) - heelStrikesRight(i), ...
                1:length(heelStrikesRight)-1);
        end


        % function RmsSignal = applyRMS(~, signal, fs, windowMs)
        %     N = round(windowMs/1000 * fs);
        %     rmsSignal = sqrt( movmean(signal.^2, N, 'Endpoints','shrink') );
        % end


        function GaitCycles(app, Data)


            if isempty(app.tree)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ...
                    'Please upload .mvnx file first', ...
                    'Data Error');
                return
            end


            % Select joint type based on dropdown value
            switch app.JointTypeDropDown.Value
                case 'Ankle'
                    jointLabelR = 'jRightAnkle';
                    jointLabelL = 'jLeftAnkle';
                    yLabelStr = 'Ankle Angle';
                case 'Knee'
                    jointLabelR = 'jRightKnee';
                    jointLabelL = 'jLeftKnee';
                    yLabelStr = 'Knee Angle';
                case 'Hip'
                    jointLabelR = 'jRightHip';
                    jointLabelL = 'jLeftHip';
                    yLabelStr = 'Hip Angle';
                case 'L5S1'
                    jointLabelR = 'jL5S1';
                    jointLabelL = 'jL5S1'; % Spinal joints are not side-specific
                    yLabelStr = 'L5S1 Angle';
                case 'L4L3'
                    jointLabelR = 'jL4L3';
                    jointLabelL = 'jL4L3';
                    yLabelStr = 'L4L3 Angle';
                case 'L1T12'
                    jointLabelR = 'jL1T12';
                    jointLabelL = 'jL1T12';
                    yLabelStr = 'L1T12 Angle';
                case 'T9T8'
                    jointLabelR = 'jT9T8';
                    jointLabelL = 'jT9T8';
                    yLabelStr = 'T9T8 Angle';
                case 'T1C7'
                    jointLabelR = 'jT1C7';
                    jointLabelL = 'jT1C7';
                    yLabelStr = 'T1C7 Angle';
                case 'C1Head'
                    jointLabelR = 'jC1Head';
                    jointLabelL = 'jC1Head';
                    yLabelStr = 'C1Head Angle';
                case 'Shoulder'
                    jointLabelR = 'jRightShoulder';
                    jointLabelL = 'jLeftShoulder';
                    yLabelStr = 'Shoulder Angle';
                case 'Elbow'
                    jointLabelR = 'jRightElbow';
                    jointLabelL = 'jLeftElbow';
                    yLabelStr = 'Elbow Angle';
                case 'Wrist'
                    jointLabelR = 'jRightWrist';
                    jointLabelL = 'jLeftWrist';
                    yLabelStr = 'Wrist Angle';
                otherwise
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Unknown joint type.', 'Error');
                    return;
            end

           
            switch app.DropDown_2.Value
                case 'Flexion/Extension'
                    location = 3;
                    name = ' Flexion/Extension';
                case 'Internal/External'
                    location = 1;
                    name = ' Internal/External';
                case 'Abduction/Adduction'
                    location = 2;
                    name = ' Abduction/Adduction';
                otherwise
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Error, chose movement type.', 'Error');
                    return;
            end

            % Extract joint angles
            LeftJointIndex = find(strcmp({Data.jointData.label}, jointLabelL));
            RightJointIndex = find(strcmp({Data.jointData.label}, jointLabelR));

            if isempty(LeftJointIndex) || isempty(RightJointIndex)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Joint data not found.', 'Error');
                return;
            end

            LeftJointAngles = Data.jointData(LeftJointIndex).jointAngle;
            LeftflexAngle = LeftJointAngles(:, location);
            RightJointAngles = Data.jointData(RightJointIndex).jointAngle;
            RightflexAngle = RightJointAngles(:, location);

            % Get the foot contact labels
            labels = {Data.footContact.label};

            % Find the index for the left and right foot labels
            LeftFootHeelIndex = find(strcmp(labels, 'LeftFoot_Heel'));
            
            RightFootHeelIndex = find(strcmp(labels, 'RightFoot_Heel'));
           
            if isempty(LeftFootHeelIndex)
                    
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Foot contact data not found.', 'Error');
                return;
            end

            % Extract the corresponding foot contact data
            footRawLeftHeel = Data.footContact(LeftFootHeelIndex).footContacts;
            
            footRawRightHeel = Data.footContact(RightFootHeelIndex).footContacts;
           
            % Find heel strikes for the left foot (transition from 0 to 1)
            heelStrikesLeft = find(diff(footRawLeftHeel) == 1) + 1;
            if length(heelStrikesLeft) >= 2
                % Get the first and second heel strikes for left foot
                idx1 = heelStrikesLeft(1);
                idx2 = heelStrikesLeft(2);

                % Normalize the gait cycle (from 0 to 100%)
                LeftflexCycle = LeftflexAngle(idx1:idx2);
                N = length(LeftflexCycle);
                LeftgaitPerc = linspace(0, 100, N);

                % Plot the gait cycle for the left foot
                cla(app.UIAxes)
                plot(app.UIAxes, LeftgaitPerc, LeftflexCycle, 'LineWidth', 2);
                title(app.UIAxes, ['Left ', app.JointTypeDropDown.Value, name, ' (1 Gait Cycle)']);
                xlabel(app.UIAxes, 'Gait Cycle (%)');
                ylabel(app.UIAxes, yLabelStr);
                grid(app.UIAxes, 'on');
            else
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Not enough heel strikes found for Left Foot.', 'Error');
            end

            % Find heel strikes for the right foot (transition from 0 to 1)
            heelStrikesRight = find(diff(footRawRightHeel) == 1) + 1;
            if length(heelStrikesRight) >= 2
                % Get the first and second heel strikes for right foot
                idx3 = heelStrikesRight(1);
                idx4 = heelStrikesRight(2);

                % Normalize the gait cycle (from 0 to 100%)
                RightflexCycle = RightflexAngle(idx3:idx4);
                N = length(RightflexCycle);
                RightgaitPerc = linspace(0, 100, N);

                % Plot the gait cycle for the right foot
                cla(app.UIAxes_2)
                plot(app.UIAxes_2, RightgaitPerc, RightflexCycle, 'LineWidth', 2);
                title(app.UIAxes_2, ['Right ', app.JointTypeDropDown.Value, name, ' (1 Gait Cycle)']);
                xlabel(app.UIAxes_2, 'Gait Cycle (%)');
                ylabel(app.UIAxes_2, yLabelStr);
                grid(app.UIAxes_2, 'on');
            else
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Not enough heel strikes found for Right Foot.', 'Error');
            end
        end


        function MultipleGaitCycles(app, Data)


            if isempty(app.tree)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ...
                    'Please upload .mvnx file first', ...
                    'Data Error');
                return
            end


            % Select joint type based on dropdown value
            switch app.JointTypeDropDown.Value
                case 'Ankle'
                    jointLabelR = 'jRightAnkle';
                    jointLabelL = 'jLeftAnkle';
                    yLabelStr = 'Ankle Dorsiflexion/Plantarflexion Angle';
                case 'Knee'
                    jointLabelR = 'jRightKnee';
                    jointLabelL = 'jLeftKnee';
                    yLabelStr = 'Knee Flexion/Extension Angle';
                case 'Hip'
                    jointLabelR = 'jRightHip';
                    jointLabelL = 'jLeftHip';
                    yLabelStr = 'Hip Flexion/Extension Angle';
                case 'L5S1'
                    jointLabelR = 'jL5S1';
                    jointLabelL = 'jL5S1'; % Spinal joints are not side-specific
                    yLabelStr = 'L5S1 Flexion/Extension Angle';
                case 'L4L3'
                    jointLabelR = 'jL4L3';
                    jointLabelL = 'jL4L3';
                    yLabelStr = 'L4L3 Flexion/Extension Angle';
                case 'L1T12'
                    jointLabelR = 'jL1T12';
                    jointLabelL = 'jL1T12';
                    yLabelStr = 'L1T12 Flexion/Extension Angle';
                case 'T9T8'
                    jointLabelR = 'jT9T8';
                    jointLabelL = 'jT9T8';
                    yLabelStr = 'T9T8 Flexion/Extension Angle';
                case 'T1C7'
                    jointLabelR = 'jT1C7';
                    jointLabelL = 'jT1C7';
                    yLabelStr = 'T1C7 Flexion/Extension Angle';
                case 'C1Head'
                    jointLabelR = 'jC1Head';
                    jointLabelL = 'jC1Head';
                    yLabelStr = 'C1Head Flexion/Extension Angle';
                case 'Shoulder'
                    jointLabelR = 'jRightShoulder';
                    jointLabelL = 'jLeftShoulder';
                    yLabelStr = 'Shoulder Flexion/Extension Angle';
                case 'Elbow'
                    jointLabelR = 'jRightElbow';
                    jointLabelL = 'jLeftElbow';
                    yLabelStr = 'Elbow Flexion/Extension Angle';
                case 'Wrist'
                    jointLabelR = 'jRightWrist';
                    jointLabelL = 'jLeftWrist';
                    yLabelStr = 'Wrist Flexion/Extension Angle';
                otherwise
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Unknown joint type.', 'Error');
                    return;
            end

            % Determine movement type and set location
            switch app.DropDown_2.Value
                case 'Flexion/Extension'
                    location = 3;
                    titleStrLeft = ['Multiple ', app.JointTypeDropDown.Value, ' Flexion/Extension (Multiple Gait Cycles) Left'];
                    titleStrRight = ['Multiple ', app.JointTypeDropDown.Value, ' Flexion/Extension (Multiple Gait Cycles) Right'];
                case 'Internal/External'
                    location = 1;
                    titleStrLeft = ['Multiple ', app.JointTypeDropDown.Value, ' Internal/External (Multiple Gait Cycles) Left'];
                    titleStrRight = ['Multiple ', app.JointTypeDropDown.Value, ' Internal/External (Multiple Gait Cycles) Right'];
                case 'Abduction/Adduction'
                    location = 2;
                    titleStrLeft = ['Multiple ', app.JointTypeDropDown.Value, ' Abduction/Adduction (Multiple Gait Cycles) Left'];
                    titleStrRight = ['Multiple ', app.JointTypeDropDown.Value, ' Abduction/Adduction (Multiple Gait Cycles) Right'];
                otherwise
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Error, chose movement type.', 'Error');
                    return;
            end

            % Extract joint angles
            LeftJointIndex = find(strcmp({Data.jointData.label}, jointLabelL));
            RightJointIndex = find(strcmp({Data.jointData.label}, jointLabelR));

            if isempty(LeftJointIndex) || isempty(RightJointIndex)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Joint data not found.', 'Error');
                return;
            end

            LeftJointAngles = Data.jointData(LeftJointIndex).jointAngle;
            LeftAngle = LeftJointAngles(:, location);
            RightJointAngles = Data.jointData(RightJointIndex).jointAngle;
            RightAngle = RightJointAngles(:, location);

            % Get foot contact data
            labels = {Data.footContact.label};
            LeftFootHeelIndex = find(strcmp(labels, 'LeftFoot_Heel'));
            LeftFootToeIndex = find(strcmp(labels, 'LeftFoot_Toe'));
            RightFootHeelIndex = find(strcmp(labels, 'RightFoot_Heel'));
            RightFootToeIndex = find(strcmp(labels, 'RightFoot_Toe'));

            if isempty(LeftFootHeelIndex) || isempty(LeftFootToeIndex) || ...
                    isempty(RightFootHeelIndex) || isempty(RightFootToeIndex)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Foot contact data not found.', 'Error');
                return;
            end

            footRawLeftHeel = Data.footContact(LeftFootHeelIndex).footContacts;
            footRawRightHeel = Data.footContact(RightFootHeelIndex).footContacts;
            footRawLeftToe = Data.footContact(LeftFootToeIndex).footContacts;
            footRawRightToe = Data.footContact(RightFootToeIndex).footContacts;

            % Find heel strikes and toe releases
            heelStrikesLeft = find(diff(footRawLeftHeel) == 1) + 1;
            heelStrikesRight = find(diff(footRawRightHeel) == 1) + 1;
            toeReleasesLeft = find(diff(footRawLeftToe) == -1) + 1;
            toeReleasesRight = find(diff(footRawRightToe) == -1) + 1;

            app.StrikesLeft = heelStrikesLeft;
            app.StrikesRight = heelStrikesRight;

            % Ensure enough data
            if length(heelStrikesLeft) < 2 || length(heelStrikesRight) < 2 || ...
                    length(toeReleasesLeft) < 2 || length(toeReleasesRight) < 2
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Not enough heel strikes or toe releases found.', 'Error');
                return;
            end

            numGaitCycles = min(length(heelStrikesLeft), length(heelStrikesRight)) - 1;
            app.numberOfGaitCycles = numGaitCycles;

            % Initialize plots
            cla(app.UIAxes_3); hold(app.UIAxes_3, 'on');
            cla(app.UIAxes_4); hold(app.UIAxes_4, 'on');

            % Initialize arrays for features
            leftOverallMin = zeros(numGaitCycles, 1);
            leftOverallMax = zeros(numGaitCycles, 1);
            leftStanceMin = zeros(numGaitCycles, 1);
            leftStanceMax = zeros(numGaitCycles, 1);
            leftSwingMin = zeros(numGaitCycles, 1);
            leftSwingMax = zeros(numGaitCycles, 1);
            leftFootStrike = zeros(numGaitCycles, 1);
            leftFootRelease = zeros(numGaitCycles, 1);
            rightOverallMin = zeros(numGaitCycles, 1);
            rightOverallMax = zeros(numGaitCycles, 1);
            rightStanceMin = zeros(numGaitCycles, 1);
            rightStanceMax = zeros(numGaitCycles, 1);
            rightSwingMin = zeros(numGaitCycles, 1);
            rightSwingMax = zeros(numGaitCycles, 1);
            rightFootStrike = zeros(numGaitCycles, 1);
            rightFootRelease = zeros(numGaitCycles, 1);

            % Loop over gait cycles
            for i = 1:numGaitCycles
                idx1 = heelStrikesLeft(i);
                idx2 = heelStrikesLeft(i + 1);
                idx3 = heelStrikesRight(i);
                idx4 = heelStrikesRight(i + 1);
                toeIdxLeft = toeReleasesLeft(i);
                toeIdxRight = toeReleasesRight(i);

                % Ensure indices are within bounds
                if idx2 > length(LeftAngle) || idx4 > length(RightAngle) || ...
                        toeIdxLeft > length(LeftAngle) || toeIdxRight > length(RightAngle)
                    continue; % Skip this cycle if indices are invalid
                end

                % Extract cycles
                LeftCycle = LeftAngle(idx1:idx2);
                RightCycle = RightAngle(idx3:idx4);

                % Normalize to 0-100%
                LeftgaitPerc = linspace(0, 100, length(LeftCycle));
                RightgaitPerc = linspace(0, 100, length(RightCycle));

                % Plot cycles
                plot(app.UIAxes_3, LeftgaitPerc, LeftCycle, 'LineWidth', 0.5, 'DisplayName', ['Left Cycle ' num2str(i)]);
                plot(app.UIAxes_4, RightgaitPerc, RightCycle, 'LineWidth', 0.5, 'DisplayName', ['Right Cycle ' num2str(i)]);

                % Segment stance (0-60%) and swing (60-100%)
                stanceEnd = round(0.6 * length(LeftCycle));
                swingStart = stanceEnd + 1;
                if stanceEnd >= length(LeftCycle) || swingStart > length(LeftCycle)
                    continue; % Skip if segmentation fails
                end
                leftStance = LeftCycle(1:stanceEnd);
                leftSwing = LeftCycle(swingStart:end);
                rightStance = RightCycle(1:stanceEnd);
                rightSwing = RightCycle(swingStart:end);

                % Extract features
                leftOverallMin(i) = min(LeftCycle);
                leftOverallMax(i) = max(LeftCycle);
                leftStanceMin(i) = min(leftStance);
                leftStanceMax(i) = max(leftStance);
                leftSwingMin(i) = min(leftSwing);
                leftSwingMax(i) = max(leftSwing);
                leftFootStrike(i) = LeftAngle(idx1);
                leftFootRelease(i) = LeftAngle(toeIdxLeft);
                rightOverallMin(i) = min(RightCycle);
                rightOverallMax(i) = max(RightCycle);
                rightStanceMin(i) = min(rightStance);
                rightStanceMax(i) = max(rightStance);
                rightSwingMin(i) = min(rightSwing);
                rightSwingMax(i) = max(rightSwing);
                rightFootStrike(i) = RightAngle(idx3);
                rightFootRelease(i) = RightAngle(toeIdxRight);
            end

            % Finalize plots
            xlabel(app.UIAxes_3, 'Gait Cycle (%)'); ylabel(app.UIAxes_3, yLabelStr); grid(app.UIAxes_3, 'on'); hold(app.UIAxes_3, 'off');
            title(app.UIAxes_3, titleStrLeft);
            xlabel(app.UIAxes_4, 'Gait Cycle (%)'); ylabel(app.UIAxes_4, yLabelStr); grid(app.UIAxes_4, 'on'); hold(app.UIAxes_4, 'off');
            title(app.UIAxes_4, titleStrRight);

            % Compute statistics (only for valid cycles)
            validCycles = ~isnan(leftOverallMin) & ~isnan(rightOverallMin);
            if sum(validCycles) == 0
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'No valid gait cycles to analyze.', 'Error');
                return;
            end

            leftOverallMinMean = mean(leftOverallMin(validCycles)); leftOverallMinStd = std(leftOverallMin(validCycles));
            leftOverallMaxMean = mean(leftOverallMax(validCycles)); leftOverallMaxStd = std(leftOverallMax(validCycles));
            leftStanceMinMean = mean(leftStanceMin(validCycles)); leftStanceMinStd = std(leftStanceMin(validCycles));
            leftStanceMaxMean = mean(leftStanceMax(validCycles)); leftStanceMaxStd = std(leftStanceMax(validCycles));
            leftSwingMinMean = mean(leftSwingMin(validCycles)); leftSwingMinStd = std(leftSwingMin(validCycles));
            leftSwingMaxMean = mean(leftSwingMax(validCycles)); leftSwingMaxStd = std(leftSwingMax(validCycles));
            leftFootStrikeMean = mean(leftFootStrike(validCycles)); leftFootStrikeStd = std(leftFootStrike(validCycles));
            leftFootReleaseMean = mean(leftFootRelease(validCycles)); leftFootReleaseStd = std(leftFootRelease(validCycles));
            rightOverallMinMean = mean(rightOverallMin(validCycles)); rightOverallMinStd = std(rightOverallMin(validCycles));
            rightOverallMaxMean = mean(rightOverallMax(validCycles)); rightOverallMaxStd = std(rightOverallMax(validCycles));
            rightStanceMinMean = mean(rightStanceMin(validCycles)); rightStanceMinStd = std(rightStanceMin(validCycles));
            rightStanceMaxMean = mean(rightStanceMax(validCycles)); rightStanceMaxStd = std(rightStanceMax(validCycles));
            rightSwingMinMean = mean(rightSwingMin(validCycles)); rightSwingMinStd = std(rightSwingMin(validCycles));
            rightSwingMaxMean = mean(rightSwingMax(validCycles)); rightSwingMaxStd = std(rightSwingMax(validCycles));
            rightFootStrikeMean = mean(rightFootStrike(validCycles)); rightFootStrikeStd = std(rightFootStrike(validCycles));
            rightFootReleaseMean = mean(rightFootRelease(validCycles)); rightFootReleaseStd = std(rightFootRelease(validCycles));

            % Compute differences
            diffOverallMin = leftOverallMinMean - rightOverallMinMean;
            diffOverallMax = leftOverallMaxMean - rightOverallMaxMean;
            diffStanceMin = leftStanceMinMean - rightStanceMinMean;
            diffStanceMax = leftStanceMaxMean - rightStanceMaxMean;
            diffSwingMin = leftSwingMinMean - rightSwingMinMean;
            diffSwingMax = leftSwingMaxMean - rightSwingMaxMean;
            diffFootStrike = leftFootStrikeMean - rightFootStrikeMean;
            diffFootRelease = leftFootReleaseMean - rightFootReleaseMean;

            % Update summary text
            summaryText = sprintf("Created by: %s \nGait Cycles: %d\n", app.CurrentUserID, sum(validCycles));
            summaryText = summaryText + ...
                sprintf("Left Overall Min: %.2f ± %.2f\n", leftOverallMinMean, leftOverallMinStd) + ...
                sprintf("Left Overall Max: %.2f ± %.2f\n", leftOverallMaxMean, leftOverallMaxStd) + ...
                sprintf("Left Stance Min: %.2f ± %.2f\n", leftStanceMinMean, leftStanceMinStd) + ...
                sprintf("Left Stance Max: %.2f ± %.2f\n", leftStanceMaxMean, leftStanceMaxStd) + ...
                sprintf("Left Swing Min: %.2f ± %.2f\n", leftSwingMinMean, leftSwingMinStd) + ...
                sprintf("Left Swing Max: %.2f ± %.2f\n", leftSwingMaxMean, leftSwingMaxStd) + ...
                sprintf("Left At Foot Strike: %.2f ± %.2f\n", leftFootStrikeMean, leftFootStrikeStd) + ...
                sprintf("Left At Foot Release: %.2f ± %.2f\n", leftFootReleaseMean, leftFootReleaseStd) + ...
                sprintf("\nRight Overall Min: %.2f ± %.2f\n", rightOverallMinMean, rightOverallMinStd) + ...
                sprintf("Right Overall Max: %.2f ± %.2f\n", rightOverallMaxMean, rightOverallMaxStd) + ...
                sprintf("Right Stance Min: %.2f ± %.2f\n", rightStanceMinMean, rightStanceMinStd) + ...
                sprintf("Right Stance Max: %.2f ± %.2f\n", rightStanceMaxMean, rightStanceMaxStd) + ...
                sprintf("Right Swing Min: %.2f ± %.2f\n", rightSwingMinMean, rightSwingMinStd) + ...
                sprintf("Right Swing Max: %.2f ± %.2f\n", rightSwingMaxMean, rightSwingMaxStd) + ...
                sprintf("Right At Foot Strike: %.2f ± %.2f\n", rightFootStrikeMean, rightFootStrikeStd) + ...
                sprintf("Right At Foot Release: %.2f ± %.2f\n", rightFootReleaseMean, rightFootReleaseStd) + ...
                sprintf("\nDifference Overall Min: %.2f\n", diffOverallMin) + ...
                sprintf("Difference Overall Max: %.2f\n", diffOverallMax) + ...
                sprintf("Difference Stance Min: %.2f\n", diffStanceMin) + ...
                sprintf("Difference Stance Max: %.2f\n", diffStanceMax) + ...
                sprintf("Difference Swing Min: %.2f\n", diffSwingMin) + ...
                sprintf("Difference Swing Max: %.2f\n", diffSwingMax) + ...
                sprintf("Difference At Foot Strike: %.2f\n", diffFootStrike) + ...
                sprintf("Difference At Foot Release: %.2f\n", diffFootRelease);

            % Set the text in the UI
            app.GaitTextArea.Value = splitlines(summaryText);

            if isfield(app, 'StatsFigure') && ishandle(app.StatsFigure)
                delete(app.StatsFigure);
            end
            app.StatsFigure = [];

            if app.plotstatisticdatainafigure
                % Create popup figure for visualization
                fig = figure('Name', [app.JointTypeDropDown.Value, ' ', app.DropDown_2.Value, ' Gait Cycle Features'], ...
                    'NumberTitle', 'off', 'Position', [100, 100, 800, 600], 'Visible', 'on');

                % Store the figure handle in the app
                app.StatsFigure = fig;

                % Define categories and data
                categories = {'Overall Min', 'Overall Max', 'Stance Min', 'Stance Max', ...
                    'Swing Min', 'Swing Max', 'Foot Strike', 'Foot Release'};
                nCategories = length(categories);

                % Data for bar chart
                leftMeans = [leftOverallMinMean, leftOverallMaxMean, leftStanceMinMean, leftStanceMaxMean, ...
                    leftSwingMinMean, leftSwingMaxMean, leftFootStrikeMean, leftFootReleaseMean];
                leftErrors = [leftOverallMinStd, leftOverallMaxStd, leftStanceMinStd, leftStanceMaxStd, ...
                    leftSwingMinStd, leftSwingMaxStd, leftFootStrikeStd, leftFootReleaseStd];
                rightMeans = [rightOverallMinMean, rightOverallMaxMean, rightStanceMinMean, rightStanceMaxMean, ...
                    rightSwingMinMean, rightSwingMaxMean, rightFootStrikeMean, rightFootReleaseMean];
                rightErrors = [rightOverallMinStd, rightOverallMaxStd, rightStanceMinStd, rightStanceMaxStd, ...
                    rightSwingMinStd, rightSwingMaxStd, rightFootStrikeStd, rightFootReleaseStd];
                diffMeans = [diffOverallMin, diffOverallMax, diffStanceMin, diffStanceMax, ...
                    diffSwingMin, diffSwingMax, diffFootStrike, diffFootRelease];

                % Create grouped bar chart
                b = bar([leftMeans; rightMeans; diffMeans]', 'grouped');
                hold on;

                % Add error bars for left and right groups
                for k = 1:2 % Loop over left (k=1) and right (k=2) groups
                    ctr = b(k).XEndPoints;
                    if k == 1
                        y = leftMeans;
                        err = leftErrors;
                    elseif k == 2
                        y = rightMeans;
                        err = rightErrors;
                    end
                    errorbar(ctr, y, err, 'k.', 'LineWidth', 1, 'CapSize', 5);
                end

                % Customize the plot
                set(gca, 'XTick', 1:nCategories, 'XTickLabel', categories, 'XTickLabelRotation', 45);
                ylabel('Angle (degrees)');
                title([app.JointTypeDropDown.Value, ' ', app.DropDown_2.Value, ' Features (Left, Right, Difference)'], ...
                    'Interpreter', 'none');
                legend('Left', 'Right', 'Difference', 'Location', 'best');
                grid on;
                hold off;
                drawnow;
            else
                return;
            end
        end


        function EMGexportTextAndPlotsToPDF(app)
            import mlreportgen.dom.*

            % Prompt user for save location
            [file, path] = uiputfile('*.pdf', 'Save PDF Report As');
            if isequal(file, 0) || isequal(path, 0)
                return; % User canceled
            end
            fullFilePath = fullfile(path, file);

            % Temporary image filenames
            leftPlotFile = fullfile(tempdir, 'RawEMG.png');
            rightPlotFile = fullfile(tempdir, 'FilteredEMG.png');

            fifthPlotFile = fullfile(tempdir, 'fifth.png');
            sexthPlotFile= fullfile(tempdir, 'sixth.png');

            % Export UIAxes as images
            exportgraphics(app.UIAxes_2, leftPlotFile);
            exportgraphics(app.UIAxes, rightPlotFile);
            
            exportgraphics(app.UIAxes_5, fifthPlotFile);
            exportgraphics(app.UIAxes_6, sexthPlotFile);


            % Create PDF report
            doc = Document(fullFilePath, 'pdf');
            open(doc);

            % Add report title
            append(doc, Paragraph('EMG Feature Report'));

            % Add text from the EMG TextArea (changed from GaitTextArea to EMGTextArea)
            for i = 1:length(app.EMGTextArea.Value)
                append(doc, Paragraph(app.EMGTextArea.Value{i}));
            end

            % Add a section for plots
            append(doc, Paragraph(' '));  % Empty line
            append(doc, Paragraph('EMG Plots'));

            % Add plots to the PDF
            % Create descriptive titles using the selected joint type
            leftTitle  = sprintf('Raw %s EMG Plot:', app.DropDown_3.Value);
            rightTitle = sprintf('Filtered %s EMG Plot:', app.DropDown_3.Value);

            % Append the plots with dynamic titles
            append(doc, Paragraph(leftTitle));
            append(doc, Image(leftPlotFile));

            append(doc, Paragraph(rightTitle));
            append(doc, Image(rightPlotFile));


             append(doc, Image(fifthPlotFile));
              append(doc, Image(sexthPlotFile));


            % Finalize and close document
            close(doc);

            % Delete temporary images
            delete(leftPlotFile);
            delete(rightPlotFile);
             delete(fifthPlotFile);
             delete(sexthPlotFile);


            % Notify user
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Report with plots saved successfully.', 'Export Complete');
        end


        function exportTextAndPlotsToPDF(app)
            import mlreportgen.dom.*

            % Prompt user for save location
            [file, path] = uiputfile('*.pdf', 'Save PDF Report As');
            if isequal(file, 0) || isequal(path, 0)
                return; % User canceled
            end
            fullFilePath = fullfile(path, file);

            % Temporary image filenames
            leftPlotFile = fullfile(tempdir, 'LeftPlot.png');
            rightPlotFile = fullfile(tempdir, 'RightPlot.png');
            statsPlotFile = fullfile(tempdir, 'StatsPlot.png');

            % List of temporary files to delete
            tempFiles = {leftPlotFile, rightPlotFile};

            % Export UIAxes as images
            exportgraphics(app.UIAxes_3, leftPlotFile, 'Resolution', 300);
            exportgraphics(app.UIAxes_4, rightPlotFile, 'Resolution', 300);

            % Export the stats figure if it exists
            if  ishandle(app.StatsFigure)
                % Bring the figure to the front and ensure it’s rendered
                figure(app.StatsFigure); % Make it the current figure
                drawnow; % Force rendering
                disp('Attempting to export stats figure...');

                try
                    exportgraphics(app.StatsFigure, statsPlotFile, 'Resolution', 300); % Higher resolution
                    tempFiles{end+1} = statsPlotFile; % Add to list of files to delete
                    disp('Stats figure exported successfully.');
                catch ME
                    disp('Error exporting stats figure:');
                    disp(ME.message);
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ['Failed to export stats figure: ' ME.message], 'Export Error');
                end
            else
                disp('No stats figure found to export.');
            end

            % Create PDF report
            doc = Document(fullFilePath, 'pdf');
            open(doc);

            % Add report title
            append(doc, Paragraph('Gait Feature Report'));

            % Add text from the text area
            for i = 1:length(app.GaitTextArea.Value)
                append(doc, Paragraph(app.GaitTextArea.Value{i}));
            end

            % Add a section for plots
            append(doc, Paragraph(' '));  % Empty line
            append(doc, Paragraph('Gait Cycle Plots'));

            % Add plots with dynamic titles and scaled images
            leftTitle = sprintf('Left %s Plot:', app.JointTypeDropDown.Value);
            rightTitle = sprintf('Right %s Plot:', app.JointTypeDropDown.Value);

            % Create and scale the left plot image
            leftImage = Image(leftPlotFile);
            leftImage.Style = {Width('5in'), Height('3in')}; % Scale to fit page
            append(doc, Paragraph(leftTitle));
            append(doc, leftImage);

            % Create and scale the right plot image
            rightImage = Image(rightPlotFile);
            rightImage.Style = {Width('5in'), Height('3in')}; % Scale to fit page
            append(doc, Paragraph(rightTitle));
            append(doc, rightImage);

            % Add the stats plot if it exists
            if  ishandle(app.StatsFigure) && exist(statsPlotFile, 'file')
                append(doc, Paragraph(' '));  % Empty line
                append(doc, Paragraph('Gait Cycle Statistics Plot'));

                % Create and scale the stats plot image
                statsImage = Image(statsPlotFile);
                statsImage.Style = {Width('5in'), Height('3in')}; % Scale to fit page
                append(doc, statsImage);
            end

            % Finalize and close document
            close(doc);

            % Delete temporary images
            for i = 1:length(tempFiles)
                if exist(tempFiles{i}, 'file')
                    delete(tempFiles{i});
                end
            end

            % Notify user
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Report with plots saved successfully.', 'Export Complete');
        end


        function SyncAndSegmentEmgWithGait(app)
            % Check if required data is available
            if isempty(app.filteredEmg) || isempty(app.StrikesLeft) || isempty(app.StrikesRight) || isempty(app.tree.jointData)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please process EMG and gait data first.', 'Error');
                return;
            end

            % Get sampling rates
            emgFs = app.fs; % EMG sampling rate (12x1 double, e.g., 2148.148193359375)
            % Convert gait frame rate to numeric if it's a string
            if ischar(app.tree.metaData.subject_frameRate) || isstring(app.tree.metaData.subject_frameRate)
                gaitFs = str2double(app.tree.metaData.subject_frameRate); % e.g., '30' to 30
            else
                gaitFs = app.tree.metaData.subject_frameRate; % Already numeric
            end

            % Validate sampling rates
            if ~isnumeric(gaitFs) || isnan(gaitFs) || gaitFs <= 0
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid gait frame rate in metaData.', 'Error');
                return;
            end
            if ~isnumeric(emgFs) || isempty(emgFs)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid EMG sampling rate: not numeric or empty.', 'Error');
                return;
            end
            % Check if all emgFs values are equal (within tolerance)
            if any(abs(emgFs - emgFs(1)) > 1e-6)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'EMG sampling rates are not consistent across channels.', 'Error');
                return;
            end
            % Round emgFs to nearest integer for resampling
            emgFs = round(emgFs(1)); % e.g., 2148.148193359375 to 2148
            if emgFs <= 0
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid EMG sampling rate: must be positive.', 'Error');
                return;
            end

            % Get number of gait frames
            numGaitFrames = size(app.tree.jointData(1).jointAngle, 1); % e.g., 3646 frames

            % Resample EMG to match gait frame rate
            try
                emgResampled = resample(app.filteredEmg, gaitFs, emgFs);
            catch err
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Resampling failed: %s', err.message), 'Error');
                return;
            end

            % Trim or interpolate to match exact number of gait frames
            if length(emgResampled) > numGaitFrames
                emgResampled = emgResampled(1:numGaitFrames); % Trim excess
            elseif length(emgResampled) < numGaitFrames
                % Interpolate to match gait frames
                emgResampled = interp1(1:length(emgResampled), emgResampled, ...
                    linspace(1, length(emgResampled), numGaitFrames), 'linear');
            end

            % Create time vector for resampled EMG
            emgTimeResampled = (0:numGaitFrames-1) / gaitFs;

            % Store resampled EMG
            app.emgResampled = emgResampled;
            app.emgTimeResampled = emgTimeResampled;

            % Initialize storage for EMG gait cycles
            numGaitCycles = app.numberOfGaitCycles; % From MultipleGaitCycles
            app.emgCyclesLeft = cell(numGaitCycles, 1); % EMG for left cycles
            app.emgCyclesRight = cell(numGaitCycles, 1); % EMG for right cycles
            app.emgCycleTimesLeft = cell(numGaitCycles, 1); % Normalized time for left cycles
            app.emgCycleTimesRight = cell(numGaitCycles, 1); % Normalized time for right cycles

            % Segment EMG for each gait cycle
            for i = 1:numGaitCycles
                % Left foot cycle
                idx1Left = app.StrikesLeft(i);
                idx2Left = app.StrikesLeft(i + 1);
                if idx2Left <= numGaitFrames
                    app.emgCyclesLeft{i} = emgResampled(idx1Left:idx2Left);
                    app.emgCycleTimesLeft{i} = linspace(0, 100, length(app.emgCyclesLeft{i}));
                end

                % Right foot cycle
                idx1Right = app.StrikesRight(i);
                idx2Right = app.StrikesRight(i + 1);
                if idx2Right <= numGaitFrames
                    app.emgCyclesRight{i} = emgResampled(idx1Right:idx2Right);
                    app.emgCycleTimesRight{i} = linspace(0, 100, length(app.emgCyclesRight{i}));
                end
            end

            % Plot EMG for left gait cycles
            cla(app.UIAxes_3); % Using UIAxes_3 as specified
            hold(app.UIAxes_3, 'on');
            colors = lines(numGaitCycles); % Color palette
            % Option 1: Plot all cycles (uncomment to use)
            % maxCyclesToPlot = numGaitCycles; % Plot all 89 cycles
            % Option 2: Plot first 10 cycles (default)
            maxCyclesToPlot = min(numGaitCycles, 10); % Limit to 10 cycles for clarity
            % Option 3: Plot a single cycle (e.g., first cycle, uncomment to use)
            % maxCyclesToPlot = 1;

            for i = 1:maxCyclesToPlot
                if ~isempty(app.emgCyclesLeft{i})
                    plot(app.UIAxes_3, app.emgCycleTimesLeft{i}, app.emgCyclesLeft{i}, ...
                        'Color', colors(i, :), 'LineWidth', 0.5, ...
                        'DisplayName', ['Left Cycle ' num2str(i)]);
                end
            end

            % Finalize plot
            title(app.UIAxes_3, sprintf('Filtered EMG (%s) - Left Gait Cycles', app.DropDown_3.Value));
            xlabel(app.UIAxes_3, 'Gait Cycle (%)');
            ylabel(app.UIAxes_3, 'EMG Amplitude');
            grid(app.UIAxes_3, 'on');

            hold(app.UIAxes_3, 'off');

            % Optional: Plot EMG and knee angle for first cycle to validate alignment
            if ~isempty(app.emgCyclesLeft{1})
                kneeIdx = find(strcmp({app.tree.jointData.label}, 'jLeftKnee'));
                if ~isempty(kneeIdx)
                    kneeAngle = app.tree.jointData(kneeIdx).jointAngle(:, 3); % Flexion angle
                    cycleKnee = kneeAngle(app.StrikesLeft(1):app.StrikesLeft(2));
                    cycleTime = app.emgCycleTimesLeft{1};
                    cla(app.UIAxes_4); % Using UIAxes_4 for validation plot
                    yyaxis(app.UIAxes_4, 'left');
                    plot(app.UIAxes_4, cycleTime, app.emgCyclesLeft{1}, 'b', 'DisplayName', 'EMG');
                    ylabel(app.UIAxes_4, 'EMG Amplitude');
                    yyaxis(app.UIAxes_4, 'right');
                    plot(app.UIAxes_4, cycleTime, cycleKnee, 'r', 'DisplayName', 'Knee Angle');
                    ylabel(app.UIAxes_4, 'Knee Flexion (deg)');
                    title(app.UIAxes_4, sprintf('EMG and Knee Angle (%s) - Left Cycle 1', app.DropDown_3.Value));
                    xlabel(app.UIAxes_4, 'Gait Cycle (%)');
                    grid(app.UIAxes_4, 'on');
                    legend(app.UIAxes_4, 'show');
                end
            end

            % Update GaitTextArea with synchronization info
            app.GaitTextArea.Value = [app.GaitTextArea.Value; ...
                sprintf('EMG resampled to %d Hz (from %d Hz), segmented into %d left and %d right gait cycles.', ...
                gaitFs, emgFs, sum(~cellfun(@isempty, app.emgCyclesLeft)), ...
                sum(~cellfun(@isempty, app.emgCyclesRight)))];
        end


        function SyncAndSegmentEmgWithGaitArea(app)
            % Check if required data is available
            if isempty(app.filteredEmg) || isempty(app.StrikesLeft) || isempty(app.StrikesRight) || isempty(app.tree.jointData)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please process EMG and gait data first.', 'Error');
                return;
            end

            % Get sampling rates
            emgFs = app.fs; % EMG sampling rate (12x1 double, e.g., 2148.148193359375)
            % Convert gait frame rate to numeric if it's a string
            if ischar(app.tree.metaData.subject_frameRate) || isstring(app.tree.metaData.subject_frameRate)
                gaitFs = str2double(app.tree.metaData.subject_frameRate); % e.g., '30' to 30
            else
                gaitFs = app.tree.metaData.subject_frameRate; % Already numeric
            end

            % Validate sampling rates
            if ~isnumeric(gaitFs) || isnan(gaitFs) || gaitFs <= 0
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid gait frame rate in metaData.', 'Error');
                return;
            end
            if ~isnumeric(emgFs) || isempty(emgFs)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid EMG sampling rate: not numeric or empty.', 'Error');
                return;
            end
            % Check if all emgFs values are equal (within tolerance)
            if any(abs(emgFs - emgFs(1)) > 1e-6)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'EMG sampling rates are not consistent across channels.', 'Error');
                return;
            end
            % Round emgFs to nearest integer for resampling
            emgFs = round(emgFs(1)); % e.g., 2148.148193359375 to 2148
            if emgFs <= 0
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid EMG sampling rate: must be positive.', 'Error');
                return;
            end

            % Get number of gait frames
            numGaitFrames = size(app.tree.jointData(1).jointAngle, 1); % e.g., 3646 frames

            % Resample EMG to match gait frame rate
            try
                emgResampled = resample(app.filteredEmg, gaitFs, emgFs);
            catch err
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Resampling failed: %s', err.message), 'Error');
                return;
            end

            % Trim or interpolate to match exact number of gait frames
            if length(emgResampled) > numGaitFrames
                emgResampled = emgResampled(1:numGaitFrames); % Trim excess
            elseif length(emgResampled) < numGaitFrames
                % Interpolate to match gait frames
                emgResampled = interp1(1:length(emgResampled), emgResampled, ...
                    linspace(1, length(emgResampled), numGaitFrames), 'linear');
            end

            % Create time vector for resampled EMG
            emgTimeResampled = (0:numGaitFrames-1) / gaitFs;

            % Store resampled EMG
            app.emgResampled = emgResampled;
            app.emgTimeResampled = emgTimeResampled;

            % Initialize storage for EMG gait cycles
            numGaitCycles = app.numberOfGaitCycles; % From MultipleGaitCycles
            app.emgCyclesLeft = cell(numGaitCycles, 1); % EMG for left cycles
            app.emgCyclesRight = cell(numGaitCycles, 1); % EMG for right cycles
            app.emgCycleTimesLeft = cell(numGaitCycles, 1); % Normalized time for left cycles
            app.emgCycleTimesRight = cell(numGaitCycles, 1); % Normalized time for right cycles

            % Segment EMG for each gait cycle
            for i = 1:numGaitCycles
                % Left foot cycle
                idx1Left = app.StrikesLeft(i);
                idx2Left = app.StrikesLeft(i + 1);
                if idx2Left <= numGaitFrames
                    app.emgCyclesLeft{i} = emgResampled(idx1Left:idx2Left);
                    app.emgCycleTimesLeft{i} = linspace(0, 100, length(app.emgCyclesLeft{i}));
                end

                % Right foot cycle
                idx1Right = app.StrikesRight(i);
                idx2Right = app.StrikesRight(i + 1);
                if idx2Right <= numGaitFrames
                    app.emgCyclesRight{i} = emgResampled(idx1Right:idx2Right);
                    app.emgCycleTimesRight{i} = linspace(0, 100, length(app.emgCyclesRight{i}));
                end
            end

            % Interpolate cycles to common length for area plot
            numPoints = 101; % Common length (0-100%)
            commonTime = linspace(0, 100, numPoints);
            interpolatedCycles = zeros(numPoints, numGaitCycles);
            for i = 1:numGaitCycles
                if ~isempty(app.emgCyclesLeft{i})
                    interpolatedCycles(:, i) = interp1(app.emgCycleTimesLeft{i}, ...
                        app.emgCyclesLeft{i}, ...
                        commonTime, 'linear');
                else
                    interpolatedCycles(:, i) = NaN; % Handle empty cycles
                end
            end

            interpolatedCyclesRight = zeros(numPoints, numGaitCycles);
            for i = 1:numGaitCycles
                if ~isempty(app.emgCyclesRight{i})
                    interpolatedCyclesRight(:, i) = interp1(app.emgCycleTimesRight{i}, ...
                        app.emgCyclesRight{i}, ...
                        commonTime, 'linear');
                else
                    interpolatedCycles(:, i) = NaN; % Handle empty cycles
                end
            end


            % Compute mean and standard deviation across cycles, handling NaNs
            meanCycle = zeros(numPoints, 1);
            stdCycle = zeros(numPoints, 1);
            for i = 1:numPoints
                validData = interpolatedCycles(i, ~isnan(interpolatedCycles(i, :)));
                if ~isempty(validData)
                    meanCycle(i) = mean(validData);
                    stdCycle(i) = std(validData);
                else
                    meanCycle(i) = NaN;
                    stdCycle(i) = NaN;
                end
            end
            upperBound = meanCycle + stdCycle;
            lowerBound = meanCycle - stdCycle;


            % Compute mean and standard deviation across cycles, handling NaNs
            meanCycleRight = zeros(numPoints, 1);
            stdCycleRight = zeros(numPoints, 1);
            for i = 1:numPoints
                validDataRight = interpolatedCyclesRight(i, ~isnan(interpolatedCyclesRight(i, :)));
                if ~isempty(validDataRight)
                    meanCycleRight(i) = mean(validDataRight);
                    stdCycleRight(i) = std(validDataRight);
                else
                    meanCycleRight(i) = NaN;
                    stdCycleRight(i) = NaN;
                end
            end
            upperBoundRight = meanCycleRight + stdCycleRight;
            lowerBoundRight = meanCycleRight - stdCycleRight;


            % Plot left EMG: single cycle as line, others as shaded area
            cla(app.UIAxes_5)
            hold(app.UIAxes_5, 'on');

            % Plot left shaded area (mean ± std)
            fill(app.UIAxes_5, [commonTime, fliplr(commonTime)], ...
                [upperBound; flipud(lowerBound)], ...
                'b', 'FaceAlpha', 0.3, 'EdgeColor', 'none', ...
                'DisplayName', 'Mean ± Std');

            % Plot first cycle as line (if not empty)
            if ~isempty(app.emgCyclesLeft{1})
                plot(app.UIAxes_5, app.emgCycleTimesLeft{1}, app.emgCyclesLeft{1}, ...
                    'r', 'LineWidth', 1.5, 'DisplayName', 'Cycle 1');
            end

            % Finalize plot left
            title(app.UIAxes_5, sprintf('Filtered EMG (%s) - Left Gait Cycles', app.DropDown_3.Value));
            xlabel(app.UIAxes_5, 'Gait Cycle (%)');
            ylabel(app.UIAxes_5, 'EMG Amplitude');
            grid(app.UIAxes_5, 'on');
            legend(app.UIAxes_5, 'show');
            hold(app.UIAxes_5, 'off');


            % Plot right EMG: single cycle as line, others as shaded area
            cla(app.UIAxes_6)
            hold(app.UIAxes_6, 'on');
            % Plot left shaded area (mean ± std)
            fill(app.UIAxes_6, [commonTime, fliplr(commonTime)], ...
                [upperBoundRight; flipud(lowerBoundRight)], ...
                'b', 'FaceAlpha', 0.3, 'EdgeColor', 'none', ...
                'DisplayName', 'Mean ± Std');
            % Plot first cycle as line (if not empty)
            if ~isempty(app.emgCyclesRight{1})
                plot(app.UIAxes_6, app.emgCycleTimesRight{1}, app.emgCyclesRight{1}, ...
                    'r', 'LineWidth', 1.5, 'DisplayName', 'Cycle 1');
            end


            % Finalize plot left
            title(app.UIAxes_6, sprintf('Filtered EMG (%s) - Right Gait', app.DropDown_3.Value));
            xlabel(app.UIAxes_6, 'Gait Cycle (%)');
            ylabel(app.UIAxes_6, 'EMG Amplitude');
            grid(app.UIAxes_6, 'on');
            legend(app.UIAxes_6, 'show');
            hold(app.UIAxes_6, 'off');


            % Update GaitTextArea with synchronization info
            app.GaitTextArea.Value = [app.GaitTextArea.Value; ...
                sprintf('EMG resampled to %d Hz (from %d Hz), segmented into %d left and %d right gait cycles.', ...
                gaitFs, emgFs, sum(~cellfun(@isempty, app.emgCyclesLeft)), ...
                sum(~cellfun(@isempty, app.emgCyclesRight)))];
        end


        function SyncAndSegmentEmgWithGaitAreaOnTopOfGait(app)
            % Check if required data is available
            if isempty(app.filteredEmg) || isempty(app.StrikesLeft) || isempty(app.StrikesRight) || isempty(app.tree.jointData)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please process EMG and gait data first.', 'Error');
                return;
            end

            % Get sampling rates
            emgFs = app.fs; % EMG sampling rate (12x1 double, e.g., 2148.148193359375)
            % Convert gait frame rate to numeric if it's a string
            if ischar(app.tree.metaData.subject_frameRate) || isstring(app.tree.metaData.subject_frameRate)
                gaitFs = str2double(app.tree.metaData.subject_frameRate); % e.g., '30' to 30
            else
                gaitFs = app.tree.metaData.subject_frameRate; % Already numeric
            end

            % Validate sampling rates
            if ~isnumeric(gaitFs) || isnan(gaitFs) || gaitFs <= 0
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid gait frame rate in metaData.', 'Error');
                return;
            end
            if ~isnumeric(emgFs) || isempty(emgFs)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid EMG sampling rate: not numeric or empty.', 'Error');
                return;
            end
            % Check if all emgFs values are equal (within tolerance)
            if any(abs(emgFs - emgFs(1)) > 1e-6)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'EMG sampling rates are not consistent across channels.', 'Error');
                return;
            end
            % Round emgFs to nearest integer for resampling
            emgFs = round(emgFs(1)); % e.g., 2148.148193359375 to 2148
            if emgFs <= 0
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid EMG sampling rate: must be positive.', 'Error');
                return;
            end

            % Get number of gait frames
            numGaitFrames = size(app.tree.jointData(1).jointAngle, 1); % e.g., 3646 frames

            % Resample EMG to match gait frame rate
            try
                emgResampled = resample(app.filteredEmg, gaitFs, emgFs);
            catch err
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Resampling failed: %s', err.message), 'Error');
                return;
            end

            % Trim or interpolate to match exact number of gait frames
            if length(emgResampled) > numGaitFrames
                emgResampled = emgResampled(1:numGaitFrames); % Trim excess
            elseif length(emgResampled) < numGaitFrames
                % Interpolate to match gait frames
                emgResampled = interp1(1:length(emgResampled), emgResampled, ...
                    linspace(1, length(emgResampled), numGaitFrames), 'linear');
            end

            % Create time vector for resampled EMG
            emgTimeResampled = (0:numGaitFrames-1) / gaitFs;

            % Store resampled EMG
            app.emgResampled = emgResampled;
            app.emgTimeResampled = emgTimeResampled;

            % Initialize storage for EMG gait cycles
            numGaitCycles = app.numberOfGaitCycles; % From MultipleGaitCycles
            app.emgCyclesLeft = cell(numGaitCycles, 1); % EMG for left cycles
            app.emgCyclesRight = cell(numGaitCycles, 1); % EMG for right cycles
            app.emgCycleTimesLeft = cell(numGaitCycles, 1); % Normalized time for left cycles
            app.emgCycleTimesRight = cell(numGaitCycles, 1); % Normalized time for right cycles

            % Segment EMG for each gait cycle
            for i = 1:numGaitCycles
                % Left foot cycle
                idx1Left = app.StrikesLeft(i);
                idx2Left = app.StrikesLeft(i + 1);
                if idx2Left <= numGaitFrames
                    app.emgCyclesLeft{i} = emgResampled(idx1Left:idx2Left);
                    app.emgCycleTimesLeft{i} = linspace(0, 100, length(app.emgCyclesLeft{i}));
                end

                % Right foot cycle
                idx1Right = app.StrikesRight(i);
                idx2Right = app.StrikesRight(i + 1);
                if idx2Right <= numGaitFrames
                    app.emgCyclesRight{i} = emgResampled(idx1Right:idx2Right);
                    app.emgCycleTimesRight{i} = linspace(0, 100, length(app.emgCyclesRight{i}));
                end
            end

            % Interpolate cycles to common length for area plot
            numPoints = 101; % Common length (0-100%)
            commonTime = linspace(0, 100, numPoints);
            interpolatedCycles = zeros(numPoints, numGaitCycles);
            for i = 1:numGaitCycles
                if ~isempty(app.emgCyclesLeft{i})
                    interpolatedCycles(:, i) = interp1(app.emgCycleTimesLeft{i}, ...
                        app.emgCyclesLeft{i}, ...
                        commonTime, 'linear');
                else
                    interpolatedCycles(:, i) = NaN; % Handle empty cycles
                end
            end

            interpolatedCyclesRight = zeros(numPoints, numGaitCycles);
            for i = 1:numGaitCycles
                if ~isempty(app.emgCyclesRight{i})
                    interpolatedCyclesRight(:, i) = interp1(app.emgCycleTimesRight{i}, ...
                        app.emgCyclesRight{i}, ...
                        commonTime, 'linear');
                else
                    interpolatedCycles(:, i) = NaN; % Handle empty cycles
                end
            end


            % Compute mean and standard deviation across cycles, handling NaNs
            meanCycle = zeros(numPoints, 1);
            stdCycle = zeros(numPoints, 1);
            for i = 1:numPoints
                validData = interpolatedCycles(i, ~isnan(interpolatedCycles(i, :)));
                if ~isempty(validData)
                    meanCycle(i) = mean(validData);
                    stdCycle(i) = std(validData);
                else
                    meanCycle(i) = NaN;
                    stdCycle(i) = NaN;
                end
            end
            upperBound = meanCycle + stdCycle;
            lowerBound = meanCycle - stdCycle;


            % Compute mean and standard deviation across cycles, handling NaNs
            meanCycleRight = zeros(numPoints, 1);
            stdCycleRight = zeros(numPoints, 1);
            for i = 1:numPoints
                validDataRight = interpolatedCyclesRight(i, ~isnan(interpolatedCyclesRight(i, :)));
                if ~isempty(validDataRight)
                    meanCycleRight(i) = mean(validDataRight);
                    stdCycleRight(i) = std(validDataRight);
                else
                    meanCycleRight(i) = NaN;
                    stdCycleRight(i) = NaN;
                end
            end
            upperBoundRight = meanCycleRight + stdCycleRight;
            lowerBoundRight = meanCycleRight - stdCycleRight;


            % Overlay EMG on top of existing gait plots
            hold(app.UIAxes_3, 'on'); % Overlay on left gait plot
            fill(app.UIAxes_3, [commonTime, fliplr(commonTime)], ...
                [upperBound; flipud(lowerBound)], ...
                'g', 'FaceAlpha', 0.3, 'EdgeColor', 'none', ...
                'DisplayName', 'EMG Mean ± Std');
            if ~isempty(app.emgCyclesLeft{1})
                plot(app.UIAxes_3, commonTime, interpolatedCycles(:, 1), ...
                    'm-', 'LineWidth', 1.5, 'DisplayName', 'EMG Cycle 1');
            end
            hold(app.UIAxes_3, 'off');

            hold(app.UIAxes_4, 'on'); % Overlay on right gait plot
            fill(app.UIAxes_4, [commonTime, fliplr(commonTime)], ...
                [upperBoundRight; flipud(lowerBoundRight)], ...
                'g', 'FaceAlpha', 0.3, 'EdgeColor', 'none', ...
                'DisplayName', 'EMG Mean ± Std');
            if ~isempty(app.emgCyclesRight{1})
                plot(app.UIAxes_4, commonTime, interpolatedCyclesRight(:, 1), ...
                    'm-', 'LineWidth', 1.5, 'DisplayName', 'EMG Cycle 1');
            end
            hold(app.UIAxes_4, 'off');

            % Update legends
            legend(app.UIAxes_3, 'show');
            legend(app.UIAxes_4, 'show');

            % Update GaitTextArea with synchronization info
            app.GaitTextArea.Value = [app.GaitTextArea.Value; ...
                sprintf('EMG resampled to %d Hz (from %d Hz), segmented into %d left and %d right gait cycles.', ...
                gaitFs, emgFs, sum(~cellfun(@isempty, app.emgCyclesLeft)), ...
                sum(~cellfun(@isempty, app.emgCyclesRight)))];
        end


        function ComputeAndPlotPSD(app, heelStrikesLeft, heelStrikesRight)


            cla(app.UIAxes_5);
            cla(app.UIAxes_6);

            % Validation: Check if gait data is uploaded and cycles are extracted
            if isempty(app.tree) || ~isfield(app.tree, 'metaData') || isempty(app.tree.metaData) || ...
                    ~isfield(app.tree.metaData, 'subject_frameRate') || isempty(app.tree.metaData.subject_frameRate)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ...
                    'Gait data has not been uploaded or metadata is incomplete. Please upload gait data first.', ...
                    'Data Error');
                return;
            end

            % Validate heel strike data
            if isempty(heelStrikesLeft) || isempty(heelStrikesRight)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ...
                    'Heel strike data (left or right) is missing. Please extract gait cycles first.', ...
                    'Data Error');
                return;
            end

            if length(heelStrikesLeft) < 2 || length(heelStrikesRight) < 2
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ...
                    'Insufficient heel strike points. At least two points per side are required to define a gait cycle.', ...
                    'Data Error');
                return;
            end

            if ~isnumeric(heelStrikesLeft) || ~isnumeric(heelStrikesRight)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ...
                    'Heel strike data must be numeric indices.', ...
                    'Data Error');
                return;
            end

            % Use filtered EMG data for the selected channel
            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Filtered EMG data not available.', 'Error');
                return;
            end


            % Debug: Export to base workspace
            assignin("base", "emgData", app.filteredEmg);

            % Extract single-channel data
            emgData = app.filteredEmg; % 1×260982 double (filtered data for selected channel)

            emgFs = app.fs(1); % Use first channel's fs (e.g., 2148.148193359375 Hz)


            emgTime = app.time(1, :); % 1×260982 double (first channel's time, assume consistency)


            % Validate data
            if length(emgData) ~= length(emgTime)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'EMG data and time vector lengths do not match. Data: %d, Time: %d', ...
                    length(emgData), length(emgTime));
                return;
            end
            if all(emgData == 0) || all(isnan(emgData))
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'EMG data contains only zeros or NaNs.', 'Error');
                return;
            end
            emgFs = round(emgFs); % Round to 2148 Hz for consistency

            % Number of gait cycles
            numGaitCycles = min(length(heelStrikesLeft) - 1, length(heelStrikesRight) - 1);
            if numGaitCycles < 1
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Not enough gait cycles for PSD analysis. Cycles: %d', numGaitCycles);
                return;
            end
            assignin("base", "numGaitCycles", numGaitCycles);
            assignin("base", "heelStrikesLeft", heelStrikesLeft);
            assignin("base", "heelStrikesRight", heelStrikesRight);

            % Scale heel strike indices from gait sampling rate to EMG sampling rate
            gaitFs = str2double(app.tree.metaData.subject_frameRate);
            if isnan(gaitFs) || gaitFs <= 0
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ...
                    'Invalid gait sampling rate. Please ensure metadata contains a valid subject_frameRate.', ...
                    'Data Error');
                return;
            end
            scalingFactor = emgFs / gaitFs;
            heelStrikesLeftScaled = round(heelStrikesLeft * scalingFactor);
            heelStrikesRightScaled = round(heelStrikesRight * scalingFactor);
            assignin("base", "heelStrikesLeftScaled", heelStrikesLeftScaled);
            assignin("base", "heelStrikesRightScaled", heelStrikesRightScaled);

            % Validate scaled indices
            if any(heelStrikesLeftScaled < 1) || any(heelStrikesLeftScaled > length(emgData)) || ...
                    any(heelStrikesRightScaled < 1) || any(heelStrikesRightScaled > length(emgData))
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, ...
                    'Scaled heel strike indices are out of bounds for the EMG data length (%d).', ...
                    length(emgData));
                return;
            end

            % Optimize pwelch parameters for fs = 2148 Hz
            windowSize = round(0.5 * emgFs); % ~1074 samples (~0.5 sec at 2148 Hz)
            noverlap = round(0.5 * windowSize); % 50% overlap
            nfft = 2^nextpow2(windowSize); % Next power of 2 (e.g., 2048)

            % Initialize arrays for PSD
            psdLeft = zeros(nfft/2+1, numGaitCycles);
            psdRight = zeros(nfft/2+1, numGaitCycles);
            f = linspace(0, emgFs/2, nfft/2+1); % Frequency vector up to Nyquist (~1074 Hz)
            assignin("base", "f", f);

            % Compute PSD for each gait cycle using scaled indices
            validLeftCount = 0;
            validRightCount = 0;
            for i = 1:numGaitCycles
                idx1 = heelStrikesLeftScaled(i);
                idx2 = heelStrikesLeftScaled(i + 1);
                idx3 = heelStrikesRightScaled(i);
                idx4 = heelStrikesRightScaled(i + 1);

                % Ensure indices are within bounds
                if idx2 > length(emgData) || idx4 > length(emgData)
                    fprintf('Cycle %d skipped: indices out of bounds (idx2=%d, idx4=%d, data length=%d)\n', ...
                        i, idx2, idx4, length(emgData));
                    continue;
                end

                % Extract EMG segments
                emgLeftCycle = emgData(idx1:idx2);
                emgRightCycle = emgData(idx3:idx4);

                % Check for sufficient data
                if length(emgLeftCycle) < windowSize
                    fprintf('Left cycle %d skipped: length %d < windowSize %d\n', i, length(emgLeftCycle), windowSize);
                    continue;
                end
                if length(emgRightCycle) < windowSize
                    fprintf('Right cycle %d skipped: length %d < windowSize %d\n', i, length(emgRightCycle), windowSize);
                    continue;
                end

                % Apply pwelch with Hamming window (default)
                [psdLeft(:, i), ~] = pwelch(emgLeftCycle, windowSize, noverlap, nfft, emgFs);
                [psdRight(:, i), ~] = pwelch(emgRightCycle, windowSize, noverlap, nfft, emgFs);
                validLeftCount = validLeftCount + 1;
                validRightCount = validRightCount + 1;
            end
            assignin("base", "psdLeft", psdLeft);
            assignin("base", "psdRight", psdRight);
            assignin("base", "validLeftCount", validLeftCount);
            assignin("base", "validRightCount", validRightCount);

            % Average PSD across cycles, handling zeros
            psdLeftMean = mean(psdLeft, 2, 'omitnan');
            psdRightMean = mean(psdRight, 2, 'omitnan');
            assignin("base", "psdLeftMean", psdLeftMean);
            assignin("base", "psdRightMean", psdRightMean);

            % Plot PSD for left cycles on UIAxes_5
            cla(app.UIAxes_5);
            hold(app.UIAxes_5, 'on');
            if any(psdLeftMean > 0)
                plot(app.UIAxes_5, f, 10*log10(psdLeftMean), 'b', 'LineWidth', 1.5, 'DisplayName', 'Left PSD');
            else
                fprintf('No valid PSD data to plot for left cycles.\n');
            end
            xlabel(app.UIAxes_5, 'Frequency (Hz)');
            ylabel(app.UIAxes_5, 'Power/Frequency (dB/Hz)');
            title(app.UIAxes_5, sprintf('PSD of Filtered EMG (%s) - Left Gait Cycles', app.DropDown_3.Value));
            grid(app.UIAxes_5, 'on');
            legend(app.UIAxes_5, 'Location', 'best');
            hold(app.UIAxes_5, 'off');

            % Plot PSD for right cycles on UIAxes_6
            cla(app.UIAxes_6);
            hold(app.UIAxes_6, 'on');
            if any(psdRightMean > 0)
                plot(app.UIAxes_6, f, 10*log10(psdRightMean), 'r', 'LineWidth', 1.5, 'DisplayName', 'Right PSD');
            else
                fprintf('No valid PSD data to plot for right cycles.\n');
            end
            xlabel(app.UIAxes_6, 'Frequency (Hz)');
            ylabel(app.UIAxes_6, 'Power/Frequency (dB/Hz)');
            title(app.UIAxes_6, sprintf('PSD of Filtered EMG (%s) - Right Gait Cycles', app.DropDown_3.Value));
            grid(app.UIAxes_6, 'on');
            legend(app.UIAxes_6, 'Location', 'best');
            hold(app.UIAxes_6, 'off');

            % Update GaitTextArea with PSD info
            app.GaitTextArea.Value = [app.GaitTextArea.Value; ...
                sprintf('PSD computed for channel %s (filtered EMG) at %d Hz using pwelch. Valid left cycles: %d, right cycles: %d', ...
                app.DropDown_3.Value, emgFs, validLeftCount, validRightCount)];


        end


function decryptFile(~,inputFilePath, outputFilePath, key)
    % Read encrypted file
    fid = fopen(inputFilePath, 'rb');
    raw = fread(fid, '*uint8');
    fclose(fid);

    % Extract IV and ciphertext
    iv = raw(1:16);
    disp(iv)
    ciphertext = raw(17:end);

    % Derive AES key (same as above)
    keyHash = java.security.MessageDigest.getInstance('SHA-256').digest(uint8(key));
    aesKey = javax.crypto.spec.SecretKeySpec(keyHash(1:32), 'AES');
    ivSpec = javax.crypto.spec.IvParameterSpec(iv);

    % Initialize Cipher
    cipher = javax.crypto.Cipher.getInstance('AES/CBC/PKCS5Padding');
    cipher.init(javax.crypto.Cipher.DECRYPT_MODE, aesKey, ivSpec);

    % Decrypt
    decrypted = cipher.doFinal(ciphertext);

    % Remove padding
    pad = decrypted(end);
    plain = decrypted(1:end-pad);

    % Write decrypted data
    fid = fopen(outputFilePath, 'wb');
    fwrite(fid, typecast(plain, 'uint8'), 'uint8');
    fclose(fid);
end
function encryptFile(~,inputFilePath, outputFilePath, key)
    % Read file as bytes
    fid = fopen(inputFilePath, 'rb');
    plainBytes = fread(fid, '*uint8');
    fclose(fid);

    % Pad data (PKCS#7 style)
    blockSize = 16;
    pad = blockSize - mod(length(plainBytes), blockSize);
    paddedData = [plainBytes(:); repmat(uint8(pad), pad, 1)];

    % Derive AES key (256-bit from SHA-256)
    keyHash = java.security.MessageDigest.getInstance('SHA-256').digest(uint8(key));
    aesKey = javax.crypto.spec.SecretKeySpec(keyHash(1:32), 'AES');

    % Generate IV (Initialization Vector)
    iv = uint8(randi([0 255], 16, 1));
    disp(iv)
    ivSpec = javax.crypto.spec.IvParameterSpec(iv);

    % Initialize Cipher
    cipher = javax.crypto.Cipher.getInstance('AES/CBC/PKCS5Padding');
    cipher.init(javax.crypto.Cipher.ENCRYPT_MODE, aesKey, ivSpec);

    % Encrypt
    encrypted = cipher.doFinal(paddedData);

    % Save IV + Encrypted data
    fid = fopen(outputFilePath, 'wb');
    fwrite(fid, iv, 'uint8');           % Save IV first (16 bytes)
    fwrite(fid, typecast(encrypted, 'uint8'), 'uint8'); % Then the ciphertext
    fclose(fid);
end


function hash = sha256(~,text)
    engine = System.Security.Cryptography.SHA256Managed;
    bytes = uint8(text);
    hashNet = uint8(engine.ComputeHash(bytes));
    hash = hashNet(:);  % Column vector
end


function hexStr = bytesToHex(~,byteArray)
    % Convert byte array to hexadecimal string
    hexStr = dec2hex(byteArray, 2);
    hexStr = reshape(hexStr', 1, []);
end

function byteArray = hexToBytes(~,hexStr)
    % Convert hexadecimal string back to byte array
    hexStr = reshape(hexStr, 2, [])';
    byteArray = uint8(hex2dec(hexStr));
end
 end


    % used database :
%    -- Create the database
% CREATE DATABASE IF NOT EXISTS gait_emg_analyzer;
% USE gait_emg_analyzer;
% 
% -- Table: users
% CREATE TABLE users (
%     id BIGINT PRIMARY KEY AUTO_INCREMENT,
%     username VARCHAR(255) UNIQUE NOT NULL,
%     password TEXT NOT NULL,
%     email VARCHAR(255)
% );
% 
% -- Table: patients
% CREATE TABLE patients (
%     id BIGINT PRIMARY KEY AUTO_INCREMENT,
%     first_name VARCHAR(255) NOT NULL,
%     last_name VARCHAR(255) NOT NULL,
%     user_id BIGINT,
%     FOREIGN KEY (user_id) REFERENCES users(id)
% );
% 
% -- Table: sessions
% CREATE TABLE sessions (
%     id BIGINT PRIMARY KEY AUTO_INCREMENT,
%     user_id BIGINT,
%     patient_id BIGINT,
%     created_at TIMESTAMP DEFAULT CURRENT_TIMESTAMP,
%     FOREIGN KEY (user_id) REFERENCES users(id),
%     FOREIGN KEY (patient_id) REFERENCES patients(id)
% );
% 
% -- Table: data
% CREATE TABLE data (
%     id BIGINT PRIMARY KEY AUTO_INCREMENT,
%     session_id BIGINT,
%     emg_file_path VARCHAR(255),  -- Path to .mat file
%     gait_file_path VARCHAR(255), -- Path to .mvnx file
%     emg_sampling_rate FLOAT,
%     gait_sampling_rate FLOAT,
%     emg_num_samples INTEGER,
%     gait_num_samples INTEGER,
%     FOREIGN KEY (session_id) REFERENCES sessions(id)
% );
% 
% -- Table: reports
% CREATE TABLE reports (
%     id BIGINT PRIMARY KEY AUTO_INCREMENT,
%     session_id BIGINT,
%     report TEXT,
%     FOREIGN KEY (session_id) REFERENCES sessions(id)
% );
% 
% -- Add indexes for performance
% CREATE INDEX idx_data_session_id ON data(session_id);
% CREATE INDEX idx_sessions_patient_id ON sessions(patient_id);
% 
% -- Insert a default user for testing (replace password with a hashed value in production)
% INSERT INTO users (username, password, email) VALUES ('admin', 'admin123', 'admin@example.com');


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp(strcat(app.joint,app.SelectedSignal))
            app.GaitandEmgAnalyzerPanel.Visible = 'on';
 app.DateDatePicker.Value = datetime('today');
            % open MySQL once
            app.Conn = database('gait_emg_analyzer','root','0988085156m', ...
                'Vendor','MySQL','Server','localhost','PortNumber',3306);
            % app.CurrentUserID = 1;  % or whatever you get from your login routine
            disp(isopen(app.Conn))


            app.movementType='Flexion/Extension';
            app.joint = 'jRightHip';
            app.SelectedSignal ='Ankle';

        end

        % Button pushed function: uploadButton
        function uploadButtonPushed(app, event)
            % Let user select an MVNX or MAT file
            [file, path] = uigetfile( ...
                {'*.mvnx;*.mat', 'MVNX and MAT Files (*.mvnx, *.mat)'; ...
                '*.mvnx', 'MVNX Files (*.mvnx)'; ...
                '*.mat', 'MAT Files (*.mat)'; ...
                '*.*', 'All Files'}, ...
                'Select MVNX or MAT File');

            % Check if user canceled the selection
            if isequal(file, 0)
                disp('User canceled file selection.');
                return;
            end

            % Construct full file path
            fullFilePath = fullfile(path, file);
            [~, ~, ext] = fileparts(file); % Get file extension

            try
                switch lower(ext)
                    case '.mvnx'
                        % Load the MVNX file
                        Tree = load_mvnx(fullFilePath);
                        app.tree = Tree;  % Store for later processing
                        app.uploadedMvnxPath = fullFilePath; % Store path for saving

                    case '.mat'
                        loadMat(app, fullFilePath);
                    otherwise
                        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Unsupported file format.', 'Error', 'Icon', 'error');
                        return;
                end

                % Display success message with file name
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('File "%s" loaded successfully!', file), 'Success', 'Icon', 'success');

            catch ME
                % Display error message
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Error loading file "%s":\n%s', file, ME.message), 'Error', 'Icon', 'error');
            end

        end

        % Button pushed function: rawemgButton
        function rawemgButtonPushed(app, event)


            if (isempty(app.EmgData)|| isempty(app.channels))
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end

            selectedText = app.DropDown_3.Value;
            disp(selectedText)
            index = app.DropDown_3.ValueIndex;
            disp('the index:')
            disp(index)
            if (index>=0)
                emgNumber = index;
                app.emgNum=emgNumber;
                disp(emgNumber)
            else
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'No muscle selected', 'Error');  % or handle the error

            end


            % extracting the data
            Edata = app.EmgData;      % EMG data: channels x samples
            Etime = app.time;         % Time data
            Efs = app.fs;             % Sampling rate

            % Make sure emgNumber is within valid range
            if emgNumber < 1 || emgNumber > size(Edata, 1)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid EMG number selected.', 'Error');
                return;
            end

            % Get the EMG signal
            emgSignal = Edata(emgNumber, :);
            % app.filteredEmg = emgSignal;

            % Determine how to get time values
            if size(Etime, 1) == size(Edata, 1)
                % Time is per channel
                emgTime = Etime(emgNumber, :);
            else
                % Time is shared or not per channel — generate using Fs
                fsValue = Efs(emgNumber);  % Use sampling rate for this EMG
                numSamples = size(Edata, 2);
                emgTime = (0:numSamples-1) / fsValue;
            end

            % Plot on UIAxes


            plot(app.UIAxes, emgTime, emgSignal);
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, sprintf('EMG %s Signal', selectedText));
            title(app.UIAxes, sprintf('Raw EMG Data of %s', selectedText));


        end

        % Button pushed function: jointmovementButton
        function jointmovementButtonPushed(app, event)


            % Check if EMGData is empty
            if isempty(app.tree)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the mvnx file first .', 'Missing Data');
                return;  % Exit the callback early
            end


            if ~isempty(app.tree)

                jtree = app.tree;
                jointdata = jtree.jointData;

                %disp(jointdata(1));
                %disp("===========================")
                %disp(jointdata(15).jointAngle);

                disp("==========================================")
                disp(app.joint);
                disp(strcmp(app.joint,jointdata(15).label));
                disp("jointdata height")
                disp( length(jointdata));
                jointindex = 0;

                if app.joint~=0

                    for i=1 : length(jointdata)
                        disp("i");
                        disp(i);
                        if strcmp(app.joint,jointdata(i).label)
                            jointindex = i ;
                            disp("jointIndex");
                            disp(jointindex);
                            break;
                        end
                    end
                else
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'choose a joint', 'Error', 'Icon', 'error');
                end


                %1  AA  ,   %2   IE               3 FE flextion extention

                if strcmp(app.movementType,"Flexion/Extension")

                    plot(app.UIAxes,jointdata(jointindex).jointAngle(:,3));
                    xlabel(app.UIAxes,'frames')
                    ylabel(app.UIAxes,'angle')
                    title (app.UIAxes, strcat(app.joint, ' Flexion/Extension'))


                    %-----------------------------------------------------------------------------------------------------------------

                elseif strcmp(app.movementType,"Internal/External")


                    plot(app.UIAxes,jointdata(jointindex).jointAngle(:,2));
                    xlabel(app.UIAxes,'frames')
                    ylabel(app.UIAxes,'angle')
                    title (app.UIAxes,strcat(app.joint,' Internal/External'))


                    %-----------------------------------------------------------------------------------------------------------------------
                elseif strcmp(app.movementType,"Abduction/Adduction")


                    plot(app.UIAxes,jointdata(jointindex).jointAngle(:,1));
                    xlabel(app.UIAxes,'frames')
                    ylabel(app.UIAxes,'angle')
                    title (app.UIAxes,strcat(app.joint,' Abduction/Adduction'))

                    %-----------------------------------------------------------------------------------------------------------------
                else
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'choose a movement', 'Error', 'Icon', 'error');

                end


            else

                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'upload a file', 'Error', 'Icon', 'error');

            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.joint = app.DropDown.Value;

        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.movementType = app.DropDown_2.Value;

        end

        % Button pushed function: MultipleGaitCyclesButton
        function MultipleGaitCyclesButtonPushed(app, event)


            MultipleGaitCycles(app,app.tree);


        end

        % Button pushed function: filteredEmgButton
        function filteredEmgButtonPushed(app, event)
            applyFilteredEMG(app);

        end

        % Value changed function: LowPassSlider
        function LowPassSliderValueChanged(app, event)
            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end

            applyFilteredEMG(app);

        end

        % Value changed function: HighPassSlider
        function HighPassSliderValueChanged(app, event)
            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end
            applyFilteredEMG(app);

        end

        % Value changed function: LowPassCheckBox
        function LowPassCheckBoxValueChanged(app, event)

            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end
            applyFilteredEMG(app);


        end

        % Value changed function: HighPassCheckBox
        function HighPassCheckBoxValueChanged(app, event)
            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end
            applyFilteredEMG(app);

        end

        % Value changed function: RMSCheckBox
        function RMSCheckBoxValueChanged(app, event)
            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end
            applyFilteredEMG(app);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)

    % Get input data
    dateValue = app.DateDatePicker.Value;

    % Validate date selection
    if isempty(dateValue) || isequal(dateValue, NaT)
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please select a valid date.', 'Input Error', 'Icon', 'error');
        return;
    end

    % Validate date range
    minDate = datetime(0000, 1, 1);
    maxDate = datetime(9999, 12, 31);
    if dateValue < minDate || dateValue > maxDate
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Selected date is out of the valid range (0000-9999).', 'Input Error', 'Icon', 'error');
        return;
    end
    sessionDate = datestr(app.DateDatePicker.Value, 'yyyy-mm-dd HH:MM:SS');
    firstName = app.firstNameEditField.Value;
    lastName = app.lastNameEditField.Value;

    % Validate inputs
    if isempty(firstName) || isempty(lastName)
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please enter both first name and last name.', 'Input Error');
        return;
    end

    % Connect to database
    conn = app.Conn;

    if isempty(conn.Message)
        % Check if patient exists or create new patient
        patientQuery = sprintf('SELECT id FROM patients WHERE first_name = ''%s'' AND last_name = ''%s''', ...
            firstName, lastName);
        patientData = fetch(conn, patientQuery);
        if isempty(patientData)
            insert(conn, 'patients', {'first_name', 'last_name', 'user_id'}, ...
                {firstName, lastName, app.LoggedInUserID});
            patientData = fetch(conn, patientQuery);
        end
        patientId = patientData.id(1);

        % Insert new session
        insert(conn, 'sessions', {'user_id', 'patient_id', 'created_at'}, ...
            {app.LoggedInUserID, patientId, sessionDate});
        sessionData = fetch(conn, 'SELECT LAST_INSERT_ID() AS id');
        sessionId = sessionData.id;

        % Save EMG data locally (no encryption)
        emgFilePath = '';
        if ~isempty(app.EmgData)
            emgDir = './data/emg';
            if ~exist(emgDir, 'dir')
                mkdir(emgDir);
            end
            Data = app.EmgData;
            Fs = app.fs;
            Time = app.time;
            Channels = app.channels;

            emgFilePath = fullfile(emgDir, sprintf('patient%d_session%d.mat', patientId, sessionId));
            save(emgFilePath, 'Data', 'Fs', 'Time', 'Channels', '-v7.3');
        else
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'No EMG data to save.', 'Data Error');
            close(conn);
            return;
        end

        % Save gait data locally (no encryption)
        gaitFilePath = '';
        if ~isempty(app.uploadedMvnxPath)
            gaitDir = './data/gait';
            if ~exist(gaitDir, 'dir')
                mkdir(gaitDir);
            end
            gaitFilePath = fullfile(gaitDir, sprintf('patient%d_session%d.mvnx', patientId, sessionId));
            if exist(app.uploadedMvnxPath, 'file')
                copyfile(app.uploadedMvnxPath, gaitFilePath);
            else
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Gait file not found at %s.', app.uploadedMvnxPath), 'Data Error');
                close(conn);
                return;
            end
        else
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'No gait (.mvnx) file uploaded.', 'Data Error');
            close(conn);
            return;
        end

        % Save report to reports table
        if ~isempty(app.ReportTextArea.Value)
            reportText = strjoin(app.ReportTextArea.Value, '\n');
            try
                insert(conn, 'reports', {'session_id', 'report'}, ...
                    {sessionId, reportText});
            catch ME
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Failed to save report: %s', ME.message), 'Database Error', 'Icon', 'error');
            end
        end

        % Insert paths into data table
        if ~isempty(emgFilePath) && ~isempty(gaitFilePath)
            insert(conn, 'data', {'session_id', 'emg_file_path', 'gait_file_path', ...
                'emg_sampling_rate', 'gait_sampling_rate', 'emg_num_samples'}, ...
                {sessionId, emgFilePath, gaitFilePath, app.fs(1), ...
                str2double(app.tree.metaData.subject_frameRate), size(app.EmgData, 2)});
        end

        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Session saved successfully.', 'Success', 'Icon', 'success');
    else
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Database connection failed: %s', conn.Message), 'Connection Error', 'Icon', 'error');
    end

        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            rawemgButtonPushed(app);
            filteredEmgButtonPushed(app);


        end

        % Value changed function: RectifyCheckBox
        function RectifyCheckBoxValueChanged(app, event)
            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end

            applyFilteredEMG(app);

        end

        % Value changed function: RectifyModeDropDown
        function RectifyModeDropDownValueChanged(app, event)
            applyFilteredEMG(app);

        end

        % Value changed function: MovAvgSlider
        function MovAvgSliderValueChanged(app, event)
            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end


            if app.MovAvgCheckBox.Value     % only recalc if user has ticked MA on
                val = round(event.Value);      % current slider value
                % Update the label you placed next to it
                app.MovAvgLabel.Text = sprintf('MovAvg: %d ms', val);

                applyFilteredEMG(app);
            end

        end

        % Value changed function: MovAvgCheckBox
        function MovAvgCheckBoxValueChanged(app, event)

            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end

            applyFilteredEMG(app);

        end

        % Value changing function: MovAvgSlider
        function MovAvgSliderValueChanging(app, event)
            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end


            val = round(event.Value);
            app.MovAvgLabel.Text = sprintf('MovAvg: %d ms', val);
            % only replot here if you’ve ensured performance is OK

        end

        % Value changing function: LowPassSlider
        function LowPassSliderValueChanging(app, event)
            if isempty(app.filteredEmg)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please upload the .mat file first ! .', 'Missing Data');
                return;  % Exit the callback early
            end


            val = round(event.Value);
            app.LowPassLabel.Text = sprintf('Low-Pass : %d ms', val);
            % only replot here if you’ve ensured performance is OK

        end

        % Value changed function: JointTypeDropDown
        function JointTypeDropDownValueChanged(app, event)
            app.SelectedSignal = app.JointTypeDropDown.Value;

        end

        % Button pushed function: SingleGaitCycleButton
        function SingleGaitCycleButtonPushed(app, event)

            GaitCycles(app,app.tree);

        end

        % Close request function: GaitandEmgsignalsAnalyzerUIFigure
        function GaitandEmgsignalsAnalyzerUIFigureCloseRequest(app, event)
            delete(findall(0, 'Type', 'figure')) % <<< This will close all figures
            if ~isempty(app.Conn) && isopen(app.Conn)
        close(app.Conn);
             end
            delete(app)

        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)

            % Get username and password from UI
            user = app.UsernameEditField.Value;
            pass = app.passwordEditField.Value;

            % Validate inputs
            if isempty(user) || isempty(pass)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please enter both username and password.', 'Input Error', 'Icon', 'error');
                return;
            end

            if ~isopen(app.Conn)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'database lost', 'Input Error', 'Icon', 'error');
                return;
            end
            % Fetch user data
            query = sprintf('SELECT id, password FROM users WHERE username = ''%s''', user);
            userData = fetch(app.Conn, query);

            if ~isempty(userData)
                storedHash = userData.password{1};
                if app.verifyPassword(pass, storedHash)
                    % Login OK: hide the login panel, show the main app
                    app.LoggedInUserID = userData.id(1);
                    app.GaitandEmgAnalyzerPanel.Visible = 'off';
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Login successful!', 'Success', 'Icon', 'success');
                else
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid username or password.', 'Login Failed', 'Icon', 'error');
                end
            else
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid username or password.', 'Login Failed', 'Icon', 'error');
            end

        end

        % Button pushed function: SignupButton
        function SignupButtonPushed(app, event)

            % Get new user details from UI
            newUser = app.usernameEditField_2.Value;
            newEmail = app.emailEditField.Value;
            newPass = app.passwordEditField_2.Value;

            % Validate inputs
            if isempty(newUser) || isempty(newEmail) || isempty(newPass)
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please fill in all fields.', 'Input Error', 'Icon', 'error');
                return;
            end

            % Check password length
            if length(newPass) < 9
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Password must be at least 9 characters long.', 'Invalid Password', 'Icon', 'error');
                return;
            end

            % Validate email format
            if isempty(regexp(newEmail, '^[\w\.-]+@[\w\.-]+\.\w{2,4}$', 'once'))
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please enter a valid email address.', 'Invalid Email', 'Icon', 'error');
                return;
            end

            % Hash the password
            hashedPass = app.hashPassword(newPass);

            % Insert new user into database
            try
                insert(app.Conn, 'users', {'username', 'password', 'email'}, ...
                    {newUser, hashedPass, newEmail});
                uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Sign-up successful! Please log in.', 'Success', 'Icon', 'success');
                % Switch back to Login tab & clear fields
                app.TabGroup2.SelectedTab = app.LoginTab;
                app.usernameEditField_2.Value = '';
                app.emailEditField.Value = '';
                app.passwordEditField_2.Value = '';
            catch ME
                if contains(ME.message, 'Duplicate entry')
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Username already exists. Please choose a different username.', ...
                        'Sign-up Failed', 'Icon', 'error');
                else
                    uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Sign-up failed: %s', ME.message), 'Error', 'Icon', 'error');
                end
            end


        end

        % Button pushed function: GetsessionsButton
        function GetsessionsButtonPushed(app, event)

        
    % Get patient name
    patientName = app.patientnameEditField.Value;
    if isempty(patientName)
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please enter a patient name.', 'Input Error', 'Icon', 'error');
        return;
    end

    % Split into first and last name
    nameParts = split(patientName);
    if length(nameParts) < 2
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please enter both first and last name (e.g., "John Doe").', 'Input Error', 'Icon', 'error');
        return;
    end
    firstName = nameParts{1};
    lastName = strjoin(nameParts(2:end), ' ');

    % Connect to database
    if isempty(app.Conn) || ~isopen(app.Conn)
        app.Conn = database('gait_emg_analyzer', 'root', 'password', ...
            'Vendor', 'MySQL', 'Server', 'localhost', 'PortNumber', 3306);
        if ~isempty(app.Conn.Message)
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Database connection failed: %s', app.Conn.Message), 'Connection Error', 'Icon', 'error');
            return;
        end
    end

    % Query patient ID
    patientQuery = sprintf('SELECT id FROM patients WHERE first_name = ''%s'' AND last_name = ''%s''', ...
        firstName, lastName);
    patientData = fetch(app.Conn, patientQuery);
    if isempty(patientData)
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('No patient found with name %s.', patientName), 'Data Error', 'Icon', 'error');
        return;
    end
    patientId = patientData.id(1);

    % Retrieve sessions, sorted by created_at
    sessionQuery = sprintf('SELECT id, created_at FROM sessions WHERE patient_id = %d ORDER BY created_at ASC', patientId);
    sessionData = fetch(app.Conn, sessionQuery);

    if isempty(sessionData)
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('No sessions found for patient %s.', patientName), 'Data Error', 'Icon', 'error');
        app.sessionDropDown.Items = {'No sessions available'};
    else
        % Generate patient-specific session numbers based on order
        numSessions = height(sessionData);
        sessionNumbers = (1:numSessions)';
        
        % Format dropdown items: "Session <number> - <date> (ID: <id>)"
        app.sessionDropDown.Items = arrayfun(@(i) sprintf('Session %d - %s (ID: %d)', ...
            sessionNumbers(i), datestr(sessionData.created_at(i), 'yyyy-mm-dd HH:MM:SS'), ...
            sessionData.id(i)), 1:numSessions, 'UniformOutput', false);
        
        % Set default value to the first item
        app.sessionDropDown.Value = app.sessionDropDown.Items{1};
    end


        end

        % Button pushed function: LoadAllDataButton
        function LoadAllDataButtonPushed(app, event)

    % Get selected session
   sessionMatch = regexp(app.sessionDropDown.Value, 'ID: (\d+)\)', 'tokens', 'once');
    if isempty(sessionMatch)
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid session selection.', 'Input Error', 'Icon', 'error');
        return;
    end
    sessionId = str2double(sessionMatch{1});
    if isempty(sessionId)
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid session selection.', 'Input Error', 'Icon', 'error');
        return;
    end

    % Connect to database
   conn = app.Conn;
    if isempty(conn.Message)
        % Retrieve file paths
        query = sprintf('SELECT emg_file_path, gait_file_path, emg_sampling_rate FROM data WHERE session_id = %d', sessionId);
        data = fetch(conn, query);

        % Load EMG data 
        emgFilePath = data.emg_file_path{1};
        if ~exist(emgFilePath, 'file')
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('EMG file not found at %s.', emgFilePath), 'File Error', 'Icon', 'error');
            return;
        end
        loadMat(app, emgFilePath);

        % Load gait data 
        gaitFilePath = data.gait_file_path{1};
        if ~exist(gaitFilePath, 'file')
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Gait file not found at %s.', gaitFilePath), 'File Error', 'Icon', 'error');
            return;
        end
       
        % Attempt to load the .mvnx file
        try
            app.tree = load_mvnx(gaitFilePath);
        catch ME
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Failed to load .mvnx file: %s', ME.message), 'File Error', 'Icon', 'error');
            return;
        end

        % Retrieve report
        reportQuery = sprintf('SELECT report FROM reports WHERE session_id = %d', sessionId);
        reportData = fetch(conn, reportQuery);
        if ~isempty(reportData) && height(reportData) > 0
            app.ReportTextArea.Value = reportData.report{1};
        else
            app.ReportTextArea.Value = '';
        end

        % Update app state
        app.emgNum = 1;
        app.SelectedSessionID = sessionId;

       
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Data loaded successfully.', 'Success', 'Icon', 'success');
    else
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Database connection failed: %s', conn.Message), 'Connection Error', 'Icon', 'error');
    end

        end

        % Button pushed function: SaveasaPDFButton
        function SaveasaPDFButtonPushed(app, event)
            exportTextAndPlotsToPDF(app);
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            % Assume 'filtered_signal' is your filtered EMG signal
            filtered_signal=app.filteredEmg;
            disp(min(filtered_signal));
            disp(max(filtered_signal));
            disp(mean(filtered_signal));

            % 1. RMS
            rmsValue = rms(filtered_signal);

            % 2. Mean Absolute Value (MAV)
            mavValue = mean(abs(filtered_signal));

            % 3. Zero Crossing Rate (ZCR)
            zeroCrossings = sum(diff(sign(filtered_signal)) ~= 0);

            % 4. Slope Sign Changes (SSC)
            slopeSignChanges = sum(diff(sign(diff(filtered_signal))) ~= 0);

            % 5. Integrated EMG (IEMG)
            iemgValue = sum(abs(filtered_signal));

            % 6. Peak-to-Peak Amplitude (P2P)
            p2pValue = max(filtered_signal) - min(filtered_signal);


            featureText = sprintf(['Created by: %s\n' ...
                'RMS: %.8f  (Root Mean Square - signal power estimate)\n' ...
                'MAV: %.8f  (Mean Absolute Value - average activity level)\n' ...
                'ZCR: %.0f  (Zero Crossing Rate - frequency of sign changes)\n' ...
                'SSC: %.0f  (Slope Sign Changes - muscle activation dynamics)\n' ...
                'IEMG: %.4f  (Integrated EMG - total muscle effort)\n' ...
                'P2P: %.4f  (Peak-to-Peak - range of signal amplitude)\n'], ...
                app.CurrentUserID, rmsValue, mavValue, zeroCrossings, slopeSignChanges, ...
                iemgValue, p2pValue);


            % Assign the feature text to the EMGTextArea (assuming EMGTextArea is the name of the TextArea)
            app.EMGTextArea.Value = featureText;  % Update the TextArea in the app


        end

        % Button pushed function: SaveasPDFButton
        function SaveasPDFButtonPushed(app, event)
            EMGexportTextAndPlotsToPDF(app);

        end

        % Button pushed function: EmgCyclesButton
        function EmgCyclesButtonPushed(app, event)
            

                SyncAndSegmentEmgWithGaitArea(app);
           
        end

        % Value changed function: plotstatisticdatainafigureCheckBox
        function plotstatisticdatainafigureCheckBoxValueChanged(app, event)
            app.plotstatisticdatainafigure = app.plotstatisticdatainafigureCheckBox.Value;

        end

        % Callback function
        function Button2Pushed(app, event)
            disp(strcat(app.joint,app.SelectedSignal))
        end

        % Callback function
        function PlotPSDCheckBoxValueChanged(app, event)
            PlotPSD = app.PlotPSDCheckBox.Value;
            if ~PlotPSD
                return
            else
                ComputeAndPlotPSD(app,app.StrikesLeft,app.StrikesRight)
            end

        end

        % Button pushed function: PlotPSDButton
        function PlotPSDButtonPushed(app, event)
            ComputeAndPlotPSD(app,app.StrikesLeft,app.StrikesRight)
        end

        % Callback function: GaitandEmgsignalsAnalyzerUIFigure, 
        % ...and 1 other component
        function appendReportOnKeyPress(app, event)
            % Check for Ctrl+R key combination
    if strcmp(event.Key, 'r') && ismember('control', event.Modifier)
        % Get the currently selected tab
        currentTab = app.TabGroup.SelectedTab;

        % Determine which text area to copy from based on the active tab
        sourceText = {};
        reportHeader = '';
        
        if currentTab == app.emgTab
            sourceText = app.EMGTextArea.Value;
            reportHeader = sprintf('--- EMG Report (Appended on %s) ---', datestr(now, 'yyyy-mm-dd HH:MM:SS'));
        elseif currentTab == app.gaitTab
            sourceText = app.GaitTextArea.Value;
            reportHeader = sprintf('--- Gait Report (Appended on %s) ---', datestr(now, 'yyyy-mm-dd HH:MM:SS'));
        else
            % If not in EMG or Gait tab, show a message and exit
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Please select the EMG or Gait tab to append a report.', 'Invalid Tab', 'Icon', 'info');
            return;
        end

        % Check if the source text area is empty
        if isempty(sourceText) || all(cellfun(@isempty, sourceText))
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'The report in the current tab is empty. Please generate a report first.', 'Empty Report', 'Icon', 'warning');
            return;
        end

        % Get the current content of ReportTextArea
        currentReport = app.ReportTextArea.Value;

        % Append a separator and the new report content
        if ~isempty(currentReport) && ~all(cellfun(@isempty, currentReport))
            % Add a newline separator if ReportTextArea already has content
            newReport = [currentReport; {''}; {reportHeader}; sourceText];
        else
            % If ReportTextArea is empty, no need for a separator
            newReport = [{reportHeader}; sourceText];
        end

        % Update ReportTextArea with the new content
        app.ReportTextArea.Value = newReport;

        % Provide user feedback
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Report appended successfully to the Report tab!', 'Success', 'Icon', 'success');

        % Optionally, switch to the Report tab to show the updated content
        app.TabGroup.SelectedTab = app.ReportTab;

        % Optionally, save the updated report to the database if a session is loaded
        if ~isempty(app.SelectedSessionID) && isopen(app.Conn)
            updatedReport = strjoin(app.ReportTextArea.Value, '\n');
            updateQuery = sprintf('INSERT INTO reports (session_id, report) VALUES (%d, ''%s'') ON DUPLICATE KEY UPDATE report = ''%s''', ...
                app.SelectedSessionID, updatedReport, updatedReport);
            exec(app.Conn, updateQuery);
        end
    end
        end

        % Button pushed function: LoadReportButton
        function LoadReportButtonPushed(app, event)
    
    % Get selected session
    sessionMatch = regexp(app.sessionDropDown.Value, 'ID: (\d+)\)', 'tokens', 'once');
    if isempty(sessionMatch)
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid session selection.', 'Input Error', 'Icon', 'error');
        return;
    end
    sessionId = str2double(sessionMatch{1});
    if isempty(sessionId) || isnan(sessionId)
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, 'Invalid session selection.', 'Input Error', 'Icon', 'error');
        return;
    end

    % Update app.SelectedSessionID for consistency
    app.SelectedSessionID = sessionId;

    % Check database connection
    if isempty(app.Conn) || ~isopen(app.Conn)
        app.Conn = database('gait_emg_analyzer', 'root', '0988085156m', ...
            'Vendor', 'MySQL', 'Server', 'localhost', 'PortNumber', 3306);
        if ~isempty(app.Conn.Message)
            uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Database connection failed: %s', app.Conn.Message), 'Connection Error', 'Icon', 'error');
            return;
        end
    end

    % Query the database for the report of the selected session
    try
        reportQuery = sprintf('SELECT report FROM reports WHERE session_id = %d', sessionId);
        reportData = fetch(app.Conn, reportQuery);
        
        % Check if data is valid and contains a report
        if ~isempty(reportData) && height(reportData) > 0 && ismember('report', reportData.Properties.VariableNames)
            if isempty(reportData.report{1})
                app.PreviousReportTextArea.Value = {'No previous report found for this session.'};
            else
                % Split the report into cell array of lines
                reportLines = splitlines(reportData.report{1});
                app.PreviousReportTextArea.Value = reportLines;
            end
        else
            app.PreviousReportTextArea.Value = {'No previous report found for this session.'};
        end
    catch ME
        uialert(app.GaitandEmgsignalsAnalyzerUIFigure, sprintf('Failed to load report: %s', ME.message), 'Error', 'Icon', 'error');
        app.PreviousReportTextArea.Value = {'Error loading report.'};
    end

    % Switch to the reportTab to show the result
    app.TabGroup.SelectedTab = app.ReportTab;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GaitandEmgsignalsAnalyzerUIFigure = uifigure('Visible', 'off');
            app.GaitandEmgsignalsAnalyzerUIFigure.Name = 'Gait and Emg signals Analyzer';
            app.GaitandEmgsignalsAnalyzerUIFigure.Icon = fullfile(pathToMLAPP, 'LOGO2.png');
            app.GaitandEmgsignalsAnalyzerUIFigure.CloseRequestFcn = createCallbackFcn(app, @GaitandEmgsignalsAnalyzerUIFigureCloseRequest, true);
            app.GaitandEmgsignalsAnalyzerUIFigure.ButtonDownFcn = createCallbackFcn(app, @appendReportOnKeyPress, true);
            app.GaitandEmgsignalsAnalyzerUIFigure.KeyPressFcn = createCallbackFcn(app, @appendReportOnKeyPress, true);
            app.GridLayout = uigridlayout(app.GaitandEmgsignalsAnalyzerUIFigure);
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_5 = uiaxes(app.GridLayout);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.GridLayout);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.homeTab = uitab(app.TabGroup);
            app.homeTab.Title = 'home              ';
            app.GridLayout4 = uigridlayout(app.homeTab);
            app.uploadButton = uibutton(app.GridLayout4, 'push');
            app.uploadButton.ButtonPushedFcn = createCallbackFcn(app, @uploadButtonPushed, true);
            app.uploadButton.Text = 'upload';
            app.saveButton = uibutton(app.GridLayout4, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.DateDatePickerLabel = uilabel(app.GridLayout4);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.GridLayout4);
            app.DateDatePicker.Value = datetime([2025 5 15]);
            app.patientsfirstnameEditFieldLabel = uilabel(app.GridLayout4);
            app.patientsfirstnameEditFieldLabel.Text = 'patient''s  first name';
            app.firstNameEditField = uieditfield(app.GridLayout4, 'text');
            app.patientslastnameEditFieldLabel = uilabel(app.GridLayout4);
            app.patientslastnameEditFieldLabel.Text = 'patient''s last name';
            app.lastNameEditField = uieditfield(app.GridLayout4, 'text');
            app.Image2 = uiimage(app.GridLayout4);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'LOGO2.png');
            app.gaitTab = uitab(app.TabGroup);
            app.gaitTab.Title = 'gait                                          ';
            app.GridLayout2 = uigridlayout(app.gaitTab);
            app.MultipleGaitCyclesButton = uibutton(app.GridLayout2, 'push');
            app.MultipleGaitCyclesButton.ButtonPushedFcn = createCallbackFcn(app, @MultipleGaitCyclesButtonPushed, true);
            app.MultipleGaitCyclesButton.Text = 'Multiple Gait Cycles';
            app.jointmovementButton = uibutton(app.GridLayout2, 'push');
            app.jointmovementButton.ButtonPushedFcn = createCallbackFcn(app, @jointmovementButtonPushed, true);
            app.jointmovementButton.Text = 'joint movement';
            app.DropDown_2 = uidropdown(app.GridLayout2);
            app.DropDown_2.Items = {'Flexion/Extension', 'Internal/External', 'Abduction/Adduction'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Flexion/Extension';
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'jRightHip', 'jRightKnee', 'jRightAnkle', 'jLeftHip', 'jLeftKnee', 'jLeftAnkle'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'jRightHip';
            app.JointTypeDropDown = uidropdown(app.GridLayout2);
            app.JointTypeDropDown.Items = {'Hip', 'Knee', 'Ankle', 'L5S1', 'L4L3', 'L1T12', 'T9T8', 'T1C7', 'C1Head', 'Shoulder', 'Elbow', 'Wrist'};
            app.JointTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @JointTypeDropDownValueChanged, true);
            app.JointTypeDropDown.Value = 'Hip';
            app.SingleGaitCycleButton = uibutton(app.GridLayout2, 'push');
            app.SingleGaitCycleButton.ButtonPushedFcn = createCallbackFcn(app, @SingleGaitCycleButtonPushed, true);
            app.SingleGaitCycleButton.Text = 'Single Gait Cycle';
            app.GaitTextArea = uitextarea(app.GridLayout2);
            app.GaitTextArea.Editable = 'off';
            app.GaitTextArea.Value = {'the Gait cycles features will be shown here,click Multiple Gait Cycles button.'};
            app.SaveasaPDFButton = uibutton(app.GridLayout2, 'push');
            app.SaveasaPDFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasaPDFButtonPushed, true);
            app.SaveasaPDFButton.Text = 'Save as a PDF';
            app.emgTab = uitab(app.TabGroup);
            app.emgTab.Title = 'emg';
            app.GridLayout3 = uigridlayout(app.emgTab);
            app.DropDown_3 = uidropdown(app.GridLayout3);
            app.DropDown_3.Items = {'You should upload EMG first!'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'You should upload EMG first!';
            app.rawemgButton = uibutton(app.GridLayout3, 'push');
            app.rawemgButton.ButtonPushedFcn = createCallbackFcn(app, @rawemgButtonPushed, true);
            app.rawemgButton.Text = 'raw emg';
            app.filteredEmgButton = uibutton(app.GridLayout3, 'push');
            app.filteredEmgButton.ButtonPushedFcn = createCallbackFcn(app, @filteredEmgButtonPushed, true);
            app.filteredEmgButton.Text = 'filtered Emg';
            app.EMGTextArea = uitextarea(app.GridLayout3);
            app.GenerateReportButton = uibutton(app.GridLayout3, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.SaveasPDFButton = uibutton(app.GridLayout3, 'push');
            app.SaveasPDFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasPDFButtonPushed, true);
            app.SaveasPDFButton.Text = 'Save as PDF';
            app.EmgCyclesButton = uibutton(app.GridLayout3, 'push');
            app.EmgCyclesButton.ButtonPushedFcn = createCallbackFcn(app, @EmgCyclesButtonPushed, true);
            app.EmgCyclesButton.Text = 'Emg Cycles';
            app.PlotPSDButton = uibutton(app.GridLayout3, 'push');
            app.PlotPSDButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPSDButtonPushed, true);
            app.PlotPSDButton.Text = 'Plot PSD';
            app.filtersTab = uitab(app.TabGroup);
            app.filtersTab.Title = 'filters';
            app.GridLayout5 = uigridlayout(app.filtersTab);
            app.LowPassCheckBox = uicheckbox(app.GridLayout5);
            app.LowPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowPassCheckBoxValueChanged, true);
            app.LowPassCheckBox.Text = '';
            app.HighPassCheckBox = uicheckbox(app.GridLayout5);
            app.HighPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighPassCheckBoxValueChanged, true);
            app.HighPassCheckBox.Text = '';
            app.RMSCheckBox = uicheckbox(app.GridLayout5);
            app.RMSCheckBox.ValueChangedFcn = createCallbackFcn(app, @RMSCheckBoxValueChanged, true);
            app.RMSCheckBox.Text = '';
            app.LowPassSlider = uislider(app.GridLayout5);
            app.LowPassSlider.Limits = [10 500];
            app.LowPassSlider.ValueChangedFcn = createCallbackFcn(app, @LowPassSliderValueChanged, true);
            app.LowPassSlider.ValueChangingFcn = createCallbackFcn(app, @LowPassSliderValueChanging, true);
            app.LowPassSlider.Value = 10;
            app.HighPassSlider = uislider(app.GridLayout5);
            app.HighPassSlider.Limits = [5 100];
            app.HighPassSlider.ValueChangedFcn = createCallbackFcn(app, @HighPassSliderValueChanged, true);
            app.HighPassSlider.Value = 5;
            app.MovAvgCheckBox = uicheckbox(app.GridLayout5);
            app.MovAvgCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovAvgCheckBoxValueChanged, true);
            app.MovAvgCheckBox.Text = '';
            app.RectifyCheckBox = uicheckbox(app.GridLayout5);
            app.RectifyCheckBox.ValueChangedFcn = createCallbackFcn(app, @RectifyCheckBoxValueChanged, true);
            app.RectifyCheckBox.Text = 'Rectify';
            app.MovAvgSlider = uislider(app.GridLayout5);
            app.MovAvgSlider.Limits = [10 200];
            app.MovAvgSlider.ValueChangedFcn = createCallbackFcn(app, @MovAvgSliderValueChanged, true);
            app.MovAvgSlider.ValueChangingFcn = createCallbackFcn(app, @MovAvgSliderValueChanging, true);
            app.MovAvgSlider.Value = 50;
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = {'|'; '|'; '|'};
            app.RectifyModeDropDown = uidropdown(app.GridLayout5);
            app.RectifyModeDropDown.Items = {'full', 'half'};
            app.RectifyModeDropDown.ValueChangedFcn = createCallbackFcn(app, @RectifyModeDropDownValueChanged, true);
            app.RectifyModeDropDown.Value = 'full';
            app.LowPassLabel = uilabel(app.GridLayout5);
            app.LowPassLabel.Text = 'Low-Pass';
            app.HighPassLabel = uilabel(app.GridLayout5);
            app.HighPassLabel.Text = 'High-Pass';
            app.RMSLabel = uilabel(app.GridLayout5);
            app.RMSLabel.Text = 'RMS';
            app.MovAvgLabel = uilabel(app.GridLayout5);
            app.MovAvgLabel.Text = 'MovAvg';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.ReportTextArea = uitextarea(app.ReportTab);
            app.ReportTextArea.Value = {'new report'};
            app.PreviousReportTextArea = uitextarea(app.ReportTab);
            app.PreviousReportTextArea.Value = {'old report'};
            app.settingsTab = uitab(app.TabGroup);
            app.settingsTab.Title = 'settings';
            app.GridLayout6 = uigridlayout(app.settingsTab);
            app.plotstatisticdatainafigureCheckBox = uicheckbox(app.GridLayout6);
            app.plotstatisticdatainafigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotstatisticdatainafigureCheckBoxValueChanged, true);
            app.plotstatisticdatainafigureCheckBox.Text = 'plot statistic data in a figure';
            app.getdataTab = uitab(app.TabGroup);
            app.getdataTab.Title = 'get data';
            app.GridLayout10 = uigridlayout(app.getdataTab);
            app.patientnameEditFieldLabel = uilabel(app.GridLayout10);
            app.patientnameEditFieldLabel.Text = 'patient name ';
            app.patientnameEditField = uieditfield(app.GridLayout10, 'text');
            app.GetsessionsButton = uibutton(app.GridLayout10, 'push');
            app.GetsessionsButton.ButtonPushedFcn = createCallbackFcn(app, @GetsessionsButtonPushed, true);
            app.GetsessionsButton.Text = 'Get sessions';
            app.sessionDropDownLabel = uilabel(app.GridLayout10);
            app.sessionDropDownLabel.Text = 'session';
            app.sessionDropDown = uidropdown(app.GridLayout10);
            app.LoadAllDataButton = uibutton(app.GridLayout10, 'push');
            app.LoadAllDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAllDataButtonPushed, true);
            app.LoadAllDataButton.Text = 'Load All Data';
            app.LoadReportButton = uibutton(app.GridLayout10, 'push');
            app.LoadReportButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReportButtonPushed, true);
            app.LoadReportButton.Text = 'Load Report';
            app.HowToUseTab = uitab(app.TabGroup);
            app.HowToUseTab.Title = 'How To Use';
            app.GridLayout11 = uigridlayout(app.HowToUseTab);
            app.HTML = uihtml(app.GridLayout11);
            app.HTML.HTMLSource = '<!DOCTYPE html><html lang="en"><head>    <meta charset="UTF-8">    <meta name="viewport" content="width=device-width, initial-scale=1.0">    <title>How to Use Gait and EMG Analyzer</title>    <style>        body {            font-family: Arial, sans-serif;            line-height: 1.6;            background-color: #f4f4f4;            padding: 20px;        }        h1, h2 {            color: #2a3d66;        }        h3 {            color: #2a7a4f;        }        p {            font-size: 1rem;            color: #333;        }        ul {            list-style-type: decimal;            margin: 15px 0;            padding-left: 20px;        }        li {            margin-bottom: 10px;        }        .steps, .emg-steps {            background-color: #e8f4f8;            padding: 10px;            border-radius: 5px;            margin-top: 20px;        }    </style></head><body>    <h1>How to Use the Gait and EMG Analyzer Application</h1>    <p>The Gait and EMG Analyzer application is a MATLAB-based app that can read MVNX and MAT files to plot Gait and EMG signals.</p>    <p>For Gait cycles, you can extract and plot a single or multiple Gait cycles from your data (.mvnx files), focusing on the Knee, Hip, and Ankle joints. An auto-generated report is provided, containing features extracted from the Gait cycle. You can save this report and the plots, then export them as a PDF file.</p>    <p>For EMG signals, you can extract and plot the raw EMG data of a muscle, apply filters to this signal, and plot the results.</p>    <h2>Steps</h2>    <div class="steps">        <h3>For Gait Cycles:</h3>        <ul>            <li>Upload a .mvnx file.</li>            <li>Go to the Gait tab.</li>            <li>On the left side, you can choose a joint and a movement type to plot the angles of that joint.</li>            <li>On the right, you can choose a joint to extract the Gait cycle.</li>            <li>Click the "Single Gait Cycle" button to plot a single Gait cycle.</li>            <li>Click the "Multiple Gait Cycles" button to plot all the Gait cycles in the provided data.</li>            <li>When the "Multiple Gait Cycles" button is clicked, an auto-generated report is shown, containing multiple features extracted from the Gait cycles.</li>            <li>Click the "Save as PDF" button to save the report and the left and right plots to your device as a PDF file.</li>        </ul>    </div>    <div class="emg-steps">        <h3>For EMG Signals:</h3>        <ul>            <li>Upload a .mat file.</li>            <li>You should be able to see the first channel data (set as the default).</li>            <li>Click on the "Filters" tab to see the available filters.</li>            <li>You can check the filter checkbox near each filter to apply it and slide the slider to adjust the value.</li>        </ul>    </div>    <p><strong>The End</strong></p></body></html>';
            app.GaitandEmgAnalyzerPanel = uipanel(app.GridLayout);
            app.GaitandEmgAnalyzerPanel.Title = 'Gait and Emg Analyzer';
            app.GridLayout7 = uigridlayout(app.GaitandEmgAnalyzerPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout7);
            app.LoginTab = uitab(app.TabGroup2);
            app.LoginTab.Title = 'Login                                         ';
            app.GridLayout8 = uigridlayout(app.LoginTab);
            app.UsernameLabel = uilabel(app.GridLayout8);
            app.UsernameLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.GridLayout8, 'text');
            app.LoginButton = uibutton(app.GridLayout8, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.WelcometoGaitandEmgSignalsAnalyzerLabel = uilabel(app.GridLayout8);
            app.WelcometoGaitandEmgSignalsAnalyzerLabel.Text = 'Welcome to Gait and Emg Signals Analyzer';
            app.PleaseLoginLabel = uilabel(app.GridLayout8);
            app.PleaseLoginLabel.Text = 'Please Login ';
            app.Image = uiimage(app.GridLayout8);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LOGO2.png');
            app.passwordEditField = wt.PasswordField(app.GridLayout8);
            app.usernameEditFieldLabel_2 = uilabel(app.GridLayout8);
            app.usernameEditFieldLabel_2.Text = 'Password';
            app.SignupTab = uitab(app.TabGroup2);
            app.SignupTab.Title = 'Signup                                   ';
            app.GridLayout9 = uigridlayout(app.SignupTab);
            app.SignupButton = uibutton(app.GridLayout9, 'push');
            app.SignupButton.ButtonPushedFcn = createCallbackFcn(app, @SignupButtonPushed, true);
            app.SignupButton.Text = 'Signup';
            app.usernameEditField_2Label = uilabel(app.GridLayout9);
            app.usernameEditField_2Label.Text = 'username';
            app.usernameEditField_2 = uieditfield(app.GridLayout9, 'text');
            app.emailEditFieldLabel = uilabel(app.GridLayout9);
            app.emailEditFieldLabel.Text = 'email';
            app.emailEditField = uieditfield(app.GridLayout9, 'text');
            app.passwordEditField_2Label = uilabel(app.GridLayout9);
            app.passwordEditField_2Label.Text = 'password';
            app.passwordEditField_2 = uieditfield(app.GridLayout9, 'text');
            app.WelcometoGaitandEmgSignalsAnalyzerLabel_2 = uilabel(app.GridLayout9);
            app.WelcometoGaitandEmgSignalsAnalyzerLabel_2.Text = 'Welcome to Gait and Emg Signals Analyzer';
            app.Image_2 = uiimage(app.GridLayout9);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'LOGO2.png');
            app.helpTab = uitab(app.TabGroup2);
            app.helpTab.Title = 'help';
            app.GridLayout12 = uigridlayout(app.helpTab);
            app.HTML2 = uihtml(app.GridLayout12);
            app.HTML2.HTMLSource = '{''<!DOCTYPE html><html lang="en"><head>  <meta charset="UTF-8" />  <meta name="viewport" content="width=device-width, initial-scale=1.0"/>  <title>Help Page</title>  <style>    body {      font-family: Arial, sans-serif;      background-color: #f4f4f4;      margin: 0;      padding: 20px;    }    .help-container {      max-width: 700px;      margin: 0 auto;      background-color: #fff;      padding: 30px;      border-radius: 12px;      box-shadow: 0 0 15px rgba(0, 0, 0, 0.1);    }    .help-text {      font-size: 18px;      color: #333;      text-align: center;      margin-bottom: 20px;      font-weight: bold;    }    .email-rows {      display: flex;      flex-direction: column;      gap: 15px;      align-items: center;    }    .email-row {      display: flex;      gap: 40px;      justify-content: center;      flex-wrap: wrap;    }    .email {      color: #007bff;      font-weight: bold;      display: flex;      align-items: center;      gap: 8px;      cursor: pointer;      user-select: none;      position: relative;    }    .email:hover {      color: #0056b3;    }    .icon {      font-size: 16px;    }    .copied {      position: absolute;      top: -20px;      font-size: 12px;      color: green;      opacity: 0;      transition: opacity 0.3s ease;    }    .email.show .copied {      opacity: 1;    }    .license-text {      font-size: 14px;      color: #666;      margin-top: 30px;      font-style: italic;      text-align: center;    }  </style></head><body>  <div class="help-container">    <p class="help-text">For help please contact:</p>    <div class="email-rows">      <div class="email-row">        <div class="email" data-email="malekshaban2004@gmail.com" onclick="copyEmail(this)">          <span class="icon">📧</span><span class="email-text">malekshaban2004@gmail.com</span>          <span class="copied">Copied!</span>        </div>        <div class="email" data-email="malek.shaaban@bahcesehir.edu.tr" onclick="copyEmail(this)">          <span class="icon">📧</span><span class="email-text">malek.shaaban@bahcesehir.edu.tr</span>          <span class="copied">Copied!</span>        </div>      </div>      <div class="email-row">        <div class="email" data-email="Rayan.abugharbieh@bahcesehir.edu.tr" onclick="copyEmail(this)">          <span class="icon">📧</span><span class="email-text">Rayan.abugharbieh@bahcesehir.edu.tr</span>          <span class="copied">Copied!</span>        </div>        <div class="email" data-email="Remy_2003@hotmail.com" onclick="copyEmail(this)">          <span class="icon">📧</span><span class="email-text">Remy_2003@hotmail.com</span>          <span class="copied">Copied!</span>        </div>      </div>      <div class="email-row">        <div class="email" data-email="Mohmad.moaena@bahcesehir.edu.tr" onclick="copyEmail(this)">          <span class="icon">📧</span><span class="email-text">Mohmad.moaena@bahcesehir.edu.tr</span>          <span class="copied">Copied!</span>        </div>        <div class="email" data-email="m.moini614@gmail.com" onclick="copyEmail(this)">          <span class="icon">📧</span><span class="email-text">m.moini614@gmail.com</span>          <span class="copied">Copied!</span>        </div>      </div>    </div>    <p class="license-text">      License: All rights to this software and its associated content are exclusively reserved by Bahçeşehir University.      Unauthorized reproduction, distribution, or modification of this material is strictly prohibited without prior written consent from the university.    </p>  </div>  <script>    function copyEmail(el) {      const email = el.getAttribute("data-email");      navigator.clipboard.writeText(email);      el.classList.add("show");      setTimeout(() => {        el.classList.remove("show");      }, 1000);    }  </script></body></html>''}';
            app.Image_3 = uiimage(app.GridLayout12);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'LOGO2.png');
            % Show the figure after all components are created
            app.GaitandEmgsignalsAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gait_And_Emg_Signals_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GaitandEmgsignalsAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GaitandEmgsignalsAnalyzerUIFigure)
        end
    end
end